classdef autocorrMMM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CurrentMapIDDropDown       matlab.ui.control.DropDown
        CurrentMapIDDropDownLabel  matlab.ui.control.Label
        SaveNavFileButton          matlab.ui.control.Button
        EditField_2                matlab.ui.control.NumericEditField
        removeposButton            matlab.ui.control.Button
        FidsLabel                  matlab.ui.control.Label
        UITable2                   matlab.ui.control.Table
        PicknewtomopositionButton  matlab.ui.control.Button
        UploadNavFileButton        matlab.ui.control.Button
        SaveImagesButton           matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        EditField                  matlab.ui.control.EditField
        removefiducialButton       matlab.ui.control.Button
        matchafiducialButton       matlab.ui.control.Button
        FmaxSlider                 matlab.ui.control.Slider
        FmaxSliderLabel            matlab.ui.control.Label
        FminSlider                 matlab.ui.control.Slider
        FminSliderLabel            matlab.ui.control.Label
        EMmaxSlider                matlab.ui.control.Slider
        EMmaxSliderLabel           matlab.ui.control.Label
        EMminSlider                matlab.ui.control.Slider
        EMminSliderLabel           matlab.ui.control.Label
        manualrefinementButton     matlab.ui.control.Button
        resetButton                matlab.ui.control.Button
        autocorrelateButton        matlab.ui.control.Button
        openmatchinghigherresolutionmapButton  matlab.ui.control.Button
        GoToEditField              matlab.ui.control.NumericEditField
        gotoROIButton              matlab.ui.control.Button
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        info % Description
        picHolder % Description
        mmminfo % Description
    end
    
    methods (Access = private)
        
        function showaxes1(app)
            app.UIAxes.Title.String=['ROI' num2str(app.info.currentROI)];
            
            EMmin=0;
            EMmax=255;
           
            Fmin=app.FminSlider.Value;
            Fmax=app.FmaxSlider.Value;

            EMdisplay=255.0.*((double(app.info.croppedEM(:,:,app.info.currentROI))-EMmin)./(EMmax-EMmin));
            EMdisplay(EMdisplay>255)=255;
            if length(size(app.info.croppedF))==4
                FL1display=255.0.*((double(app.info.croppedF(:,:,:,app.info.currentROI))-Fmin)./(Fmax-Fmin));
                FL1display(FL1display>255)=255; 
                app.mmminfo.rgb=1;
            else
                FL1display=255.0.*((double(app.info.croppedF(:,:,app.info.currentROI))-Fmin)./(Fmax-Fmin));
                FL1display(FL1display>255)=255;  
                app.mmminfo.rgb=0;
            end
        
            EMrgb=cat(3,uint8(EMdisplay),uint8(EMdisplay),uint8(EMdisplay));
            if size(FL1display,3)==3
                Frgb=uint8(FL1display);
            
            else
                Frgb=cat(3,uint8(FL1display),uint8(FL1display).*0,uint8(FL1display));
            end
            DISPLAYimage=uint8(0.5.*EMrgb)+uint8(Frgb);
            app.info.overlayROIS(:,:,:,app.info.currentROI)=DISPLAYimage;
            imshow(DISPLAYimage,[],'Parent',app.UIAxes); 
        end
        
        function showaxes2(app)
            
            if isempty(app.mmminfo.fileHolder{app.info.currentROI, 1})
                cla(app.UIAxes2);
                app.UIAxes2.Title.String=' ';
                
            else
            app.UIAxes2.Title.String=strrep(app.mmminfo.fileHolder{app.info.currentROI, 1}, '_','\_'); %strrep(app.info.fileHolder{4, 1},'_','\_');
            
            EMmin=app.EMminSlider.Value;
            EMmax=app.EMmaxSlider.Value;

           
            EMimage=double(app.mmminfo.picHolder{app.info.currentROI,1});
            
            EMnorm=255.0.*(EMimage-min(EMimage(:)))./(max(EMimage(:))-min(EMimage(:))); 
            
            EMdisplay=255.0.*(EMnorm-EMmin)./(EMmax-EMmin); 
            EMdisplay(EMdisplay<0)=0;
            EMdisplay(EMdisplay>255)=255;
            

            imshow(EMdisplay,[],'Parent',app.UIAxes2); 
            end
        end
        
        function showaxes3(app)


            if isempty(app.mmminfo.fileHolder{app.info.currentROI, 2})
                cla(app.UIAxes3);
                app.UIAxes3.Title.String=' ';
                app.matchafiducialButton.Enable='off';
                app.removefiducialButton.Enable='off';
                app.manualrefinementButton.Enable='off';
                app.EditField.Enable='off';
                app.UITable.Enable='off';
                app.PicknewtomopositionButton.Enable='off';
                app.FidsLabel.Text='0 FIDS';
                app.FidsLabel.Enable='off';
                app.UITable.Data=[];

            else
                app.FidsLabel.Enable='on';
                app.FidsLabel.Text=[num2str(size(app.mmminfo.FIDSt{app.info.currentROI},1)) ' FIDS'];
                app.matchafiducialButton.Enable='on';
                app.removefiducialButton.Enable='on';
                app.manualrefinementButton.Enable='on';
                app.EditField.Enable='on';
                app.UITable.Enable='on';
                app.PicknewtomopositionButton.Enable='on';
                if isempty(app.mmminfo.mmmF{app.info.currentROI, 2})
                    Fimage=app.mmminfo.mmmF{app.info.currentROI,1};
                    
                else
                    Fimage=app.mmminfo.mmmF{app.info.currentROI,2};
                end
                %You need to tell it to use the transformed fluorescence image
                %here if applicable
                
                app.UIAxes3.Title.String=strrep(app.mmminfo.fileHolder{app.info.currentROI, 2}, '_','\_'); 

                EMmin=app.EMminSlider.Value;
                EMmax=app.EMmaxSlider.Value;
                Fmin=app.FminSlider.Value;
                Fmax=app.FmaxSlider.Value;
    
    
                EMimage=double(app.mmminfo.picHolder{app.info.currentROI,1});
                EMnorm=255.0.*(EMimage-min(EMimage(:)))./(max(EMimage(:))-min(EMimage(:))); 
                EMdisplay=255.0.*(EMnorm-EMmin)./(EMmax-EMmin); 
                EMdisplay(EMdisplay>255)=255;
                EMdisplay(EMdisplay<0)=0;
                EMrgb=cat(3,uint8(EMdisplay),uint8(EMdisplay),uint8(EMdisplay));
                
                if app.mmminfo.rgb==1
                    FL1display=255.0.*(double(Fimage)-Fmin)./(Fmax-Fmin);
                    FL1display(FL1display>255)=255; 
                    
                    Frgb=(uint8(FL1display));
                else
                    FL1display=255.0.*((double(Fimage)-Fmin)./(Fmax-Fmin));
                    FL1display(FL1display>255)=255; 
                    
                    Frgb=cat(3,uint8(FL1display),uint8(FL1display).*0,uint8(FL1display));
                end
                
                DISPLAYimage=uint8(EMrgb)+uint8(Frgb);
    
                imshow(DISPLAYimage,[],'Parent',app.UIAxes3); 

                %now ask if there are any tomo regions that need to be
                %displayed
                if isfield(app.mmminfo,'tomopos')
                    Alltomosregs=app.mmminfo.tomopos;     
                    for ii=1:size(Alltomosregs,1)
                        if Alltomosregs(ii,1)==app.info.currentROI
                           Pt=drawpoint(app.UIAxes3, 'Position',app.mmminfo.tomopos(ii,2:3)); 
                           Pt.Label=['tomo ' num2str(ii)];
                        end
                    end
                    app.UploadNavFileButton.Enable='on';
                end
            end
            
            %display the tomo table if applicable
            if isfield(app.mmminfo, 'optionarray')

                app.UITable2.Data=cat(2,num2cell(app.mmminfo.tomopos),app.mmminfo.assignedNav);
                app.UITable2.RowName='numbered';
            end
            %%%


        end
        
        function showfids(app)
            if ~isempty(app.mmminfo.FIDSt{app.info.currentROI}) 


                hold(app.UIAxes3,'on')
                scatter(app.UIAxes3,app.mmminfo.FIDSem{app.info.currentROI}(:,1),app.mmminfo.FIDSem{app.info.currentROI}(:,2),'w.');
                scatter(app.UIAxes3,app.mmminfo.FIDSt{app.info.currentROI}(:,1),app.mmminfo.FIDSt{app.info.currentROI}(:,2),'b.');
                for i = 1:size(app.mmminfo.FIDSem{app.info.currentROI}, 1)
                    text(app.UIAxes3, app.mmminfo.FIDSem{app.info.currentROI}(i,1) + 20, app.mmminfo.FIDSem{app.info.currentROI}(i,2) + 20, ...
                        num2str(i), 'FontSize',12, 'Color','white');
                end
                hold(app.UIAxes3,'off')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, infoMOM, picHolderMOM)
            app.info=infoMOM;
            app.picHolder=picHolderMOM;
            app.info.currentROI=1;
            
            prompt = {'Enter LMM pixel size (nm):','Enter MMM pixel size (nm):', 'Mesh size (300 or 200)'};
            dlgtitle = 'pixsize';
            dims = [1 35];
            definput = {'357.544','7.852', '300'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            app.info.pixSize(1)=str2double(answer{1});
            app.info.pixSize(2)=str2double(answer{2});
            app.mmminfo.gridmesh=str2double(answer{3});
            app.mmminfo.fileHolder{app.info.ROItotN, 2}=[];
            app.mmminfo.FIDSt{app.info.ROItotN}=[];
            
            app.mmminfo.mmmF{app.info.ROItotN, 2}=[];
            app.mmminfo.T{app.info.ROItotN}=[];
            app.mmminfo.residuals{app.info.ROItotN}=[];
            app.mmminfo.rotangle{app.info.ROItotN}=[];
            app.mmminfo.mmmoffsets{app.info.ROItotN, 2}=[]; %x and y offsets to align the fluorescence to the MMM images after rotation. 
            app.mmminfo.scalefactor=4.0; %mmmimages will all be immediately downsized by 4 in order to do the correlation. No need to correlate at full resolution. If this is needed in the future, it could be rescaled upon saving the images. Otherwise, we will run into a memory problem. 

            showaxes1(app);
            showaxes2(app);
            showaxes3(app);

        end

        % Button pushed function: gotoROIButton
        function gotoROIButtonPushed(app, event)
            if app.GoToEditField.Value<=app.info.ROItotN
                app.info.currentROI=app.GoToEditField.Value;
                if app.GoToEditField.Value<app.info.ROItotN
                    app.GoToEditField.Value=app.GoToEditField.Value+1;
                end
            else 
                app.info.currentROI=app.info.ROItotN;
                app.GoToEditField.Value=app.info.ROItotN;
            end

            if isfield(app.mmminfo, 'optionarray')
                app.CurrentMapIDDropDown.Items=app.mmminfo.optionarray;
                sections= cell2mat(app.mmminfo.notesID(:, 3))+1;
                whichmont=find(sections==app.info.currentROI, 1, 'last');
                if whichmont>0
                    app.CurrentMapIDDropDown.Value=app.mmminfo.optionarray{whichmont};
                else
                    app.CurrentMapIDDropDown.Value=app.mmminfo.optionarray{2};
                end
                
            end
            assignin('base','autocorrapptotalshift', app.info.totalshift);
            showaxes1(app);
            showaxes2(app);
            showaxes3(app);
        end

        % Button pushed function: openmatchinghigherresolutionmapButton
        function openmatchinghigherresolutionmapButtonPushed(app, event)
            %Here, I am starting to use another variable called mmminfo. It
            %will hold the information of all of the mmms.
            [app.mmminfo.fileHolder{app.info.currentROI, 1}, app.mmminfo.fileHolder{app.info.currentROI, 2}, app.mmminfo.fileHolder{app.info.currentROI, 3}] = uigetfile('*','Pick your EM map', app.info.fileHolder{4, 2});
            tempimage=imread([app.mmminfo.fileHolder{app.info.currentROI, 2}, app.mmminfo.fileHolder{app.info.currentROI, 1}]);
            
            app.mmminfo.picHolder{app.info.currentROI,1}=padarray(imresize(tempimage, 1/app.mmminfo.scalefactor), [240 240], mean(tempimage(:)), 'both'); 
            %app.mmminfo.picHolder{app.info.currentROI,2} = imread([app.mmminfo.fileHolder{app.info.currentROI, 2}, app.mmminfo.fileHolder{app.info.currentROI, 1}]);
            app.mmminfo.picHolder{app.info.currentROI,1} = imgaussfilt(app.mmminfo.picHolder{app.info.currentROI,1}, 2);

            showaxes2(app);
        end

        % Button pushed function: autocorrelateButton
        function autocorrelateButtonPushed(app, event)

            %temporary cropping index. It turns out that the smaller grid
            %size causes problems when doing correlation. So, If I am using
            %300 mesh, I need to crop the images down further so I don't
            %have many grid holes in the image. Here, I will just take out
            %a quarter of the image;
            if app.mmminfo.gridmesh==200
                croptop=floor(size(app.info.croppedEM(:,:,app.info.currentROI), 1)./4);
                cropbottom=size(app.info.croppedEM(:,:,app.info.currentROI), 1)-floor(size(app.info.croppedEM(:,:,app.info.currentROI), 1)./4);
                cropleft=floor(size(app.info.croppedEM(:,:,app.info.currentROI), 2)./4);
                cropright=size(app.info.croppedEM(:,:,app.info.currentROI), 2)-floor(size(app.info.croppedEM(:,:,app.info.currentROI), 2)./4);
            elseif app.mmminfo.gridmesh==300
                croptop=1;
                cropbottom=size(app.info.croppedEM(:,:,app.info.currentROI), 1);
                cropleft=1;
                cropright=size(app.info.croppedEM(:,:,app.info.currentROI), 2);
            else
                f = uialert(app.UIFigure,'This program uses either 200 or 300 mesh grids. You have entered a different number for mesh size. Therefore, your correlation may be faulty.','unfamiliar grid mesh size');
                %The program by default will use the smaller region to
                %correlate. If the region is too large, it will just
                %correlate to the grid square. 
                croptop=floor(size(app.info.croppedEM(:,:,app.info.currentROI), 1)./4);
                cropbottom=size(app.info.croppedEM(:,:,app.info.currentROI), 1)-floor(size(app.info.croppedEM(:,:,app.info.currentROI), 1)./4);
                cropleft=floor(size(app.info.croppedEM(:,:,app.info.currentROI), 2)./4);
                cropright=size(app.info.croppedEM(:,:,app.info.currentROI), 2)-floor(size(app.info.croppedEM(:,:,app.info.currentROI), 2)./4);
            end

            %Both the LMM and the Fimage need to be cropped
            %
            MMMimage=app.mmminfo.picHolder{app.info.currentROI,1};
            LMMimage=app.info.croppedEM(croptop:cropbottom,cropleft:cropright,app.info.currentROI);
            shrinkby=4; %shrink the images by 4 to find the best rotation so it is faster
            MMMsized=double(imresize(MMMimage, (app.info.pixSize(2).*app.mmminfo.scalefactor)/(app.info.pixSize(1)))); %This now includes the scalefactor since the MMM image has been scaled
            MMMcropped=MMMsized;
            
            %Do all rotation and correlation problems with images that are
            %4x smaller for speed purposes.
            shrinkMMM=imresize(MMMsized,1./shrinkby);
            shrinkLMM=imresize(LMMimage,1./shrinkby);
            padsize=floor(240.*(app.info.pixSize(2).*app.mmminfo.scalefactor)/(app.info.pixSize(1))./shrinkby);
            increment=2;
            itnum=360/increment;
            maxcorr=zeros(itnum,1);
            %assignin('base','LMMimage', LMMimage);
            %assignin('base','shrinkMMM', shrinkMMM);
            %assignin('base','shrinkLMM',shrinkLMM);
            %assignin('base','padsize',padsize);
            angle1=floor(135./increment);
            angle2=floor(225./increment);
            
            for ii=angle1:angle2
                LMMrot=imrotate(shrinkLMM, ii*increment,'bilinear','crop');
                %assignin('base','LMMrot',LMMrot);
                %assignin('base','ii',ii);
                corrimage=normxcorr2(shrinkMMM(padsize:(size(shrinkMMM,1)-padsize),padsize:(size(shrinkMMM,2)-padsize)) , LMMrot);
                
                maxcorr(ii)=max(corrimage(:));
            end
            clear('corrimage');
            whichrot=increment.*find(maxcorr==max(maxcorr(angle1:angle2)));
            %assignin('base','whichrot',whichrot);
            %whichrot=180;
            clear('maxcorr');
            %whichrot is roughly the rotation that we need to align. We
            %will use the higher resolution images to find a better
            %rotation. 
            refinedangle=0.5;
            %use the increment from above to determine how much space we
            %need to search at this higher refined rotation
            newiterationN=increment.*2/refinedangle+1;
            findcorr=zeros(newiterationN,2);
            maxcorr=zeros(newiterationN,1);
            currentangle=zeros(newiterationN,1);
            scaledpad=floor(240.*(app.info.pixSize(2).*app.mmminfo.scalefactor)/(app.info.pixSize(1)))+20;
            for ii=1:newiterationN
                currentangle(ii)=whichrot-increment+(ii-1).*refinedangle;
                LMMrotlarge=imrotate(LMMimage, currentangle(ii),'bilinear','crop');
                %note that here we are switching to the larger size images.
                %they are no longer shrunk so that we can get a higher
                %resolution correlation. 
                smallMMMimage=MMMsized(scaledpad:(size(MMMsized,1)-scaledpad),scaledpad:(size(MMMsized,2)-scaledpad));
                corrimage=normxcorr2(smallMMMimage, LMMrotlarge);
                maxcorr(ii)=max(corrimage(:));
                [findcorr(ii,1), findcorr(ii,2)]=find(corrimage==maxcorr(ii));    
            end
            %assignin('base','LMMrotlarge',LMMrotlarge);
            %assignin('base','smallMMMimage',smallMMMimage);
            %assignin('base','corrimage',corrimage);
            %assignin('base','currentangle',currentangle);
            %assignin('base','findcorr',findcorr);
            
            whichrotfine=currentangle(maxcorr==max(maxcorr));
            %whichrotfine=180;
            app.mmminfo.rotangle{app.info.currentROI}=whichrotfine;
            foundcorr=findcorr(maxcorr==max(maxcorr), :);
            %assignin('base','foundcorr',foundcorr);
           
            yoffset = foundcorr(1)-size(smallMMMimage,1)-scaledpad; %do I need to add 20 here?
            xoffset = foundcorr(2)-size(smallMMMimage,2)-scaledpad; %do I need to add 20 here?
            %assignin('base','scaledpad', scaledpad);
            %assignin('base','yoffset1', yoffset);
            %assignin('base','xoffset1', xoffset);
            app.mmminfo.mmmoffsets{app.info.currentROI, 1}=xoffset;
            app.mmminfo.mmmoffsets{app.info.currentROI, 2}=yoffset;

            if (xoffset>100) || (yoffset>100)
                xoffset=size(LMMimage,2)./2-size(MMMsized,2)./2;
                yoffset=size(LMMimage,1)./2-size(MMMsized,1)./2;
                app.mmminfo.mmmoffsets{app.info.currentROI, 1}=xoffset;
                app.mmminfo.mmmoffsets{app.info.currentROI, 2}=yoffset;
                %assignin('base','yoffset2', yoffset);
                %assignin('base','xoffset2', xoffset);
            end
            %Yoffset and xoffset define the position on the LMMimage that
            %matches the upper left pixel on the MMMsized 
            
            
            if app.mmminfo.rgb==1
                Fimage=app.info.croppedF(croptop:cropbottom,cropleft:cropright,:,app.info.currentROI);
                %assignin('base','Fimage',Fimage);
                imagedepth=3;
                Fimagefinal=zeros(size(MMMimage,1),size(MMMimage,2),imagedepth);
                for jj=1:3
                    Fimagerot=imrotate(Fimage(:,:,jj), whichrotfine,'bilinear','crop');
                    %assignin('base','Fimagerot',Fimagerot);
                    %assignin('base','MMMsized',MMMsized);
                    %assignin('base','yoffset',yoffset);
                    %assignin('base','xoffset',xoffset);
                    Fimagecropped=Fimagerot(yoffset:(yoffset+size(MMMsized,1)-1), xoffset:(xoffset+size(MMMsized,2)-1));
                    Fimagefinal(:,:,jj)=imresize(Fimagecropped,size(MMMimage), 'nearest');
                    
                end
                app.mmminfo.mmmF{app.info.currentROI,1}=Fimagefinal;
            else 
                Fimage=app.info.croppedF(:,:,app.info.currentROI);
                imagedepth=1;
                Fimagerot=imrotate(Fimage, whichrotfine,'bilinear','crop');
                Fimagecropped=Fimagerot(yoffset:yoffset+size(MMMsized,1)-1, xoffset:xoffset+size(MMMsized,2)-1);
                Fimagefinal=imresize(Fimagecropped,size(MMMimage), 'nearest');
                app.mmminfo.mmmF{app.info.currentROI,1}=Fimagefinal;
            end
            app.mmminfo.fileHolder{app.info.currentROI, 2}=[app.mmminfo.fileHolder{app.info.currentROI, 1}, ' / ROI', num2str(app.info.currentROI)];
            
                app.matchafiducialButton.Enable='on';

                app.UITable.Enable='on';
                app.PicknewtomopositionButton.Enable='on';
            
            showaxes3(app);
            
        end

        % Value changed function: FmaxSlider
        function FmaxSliderValueChanged(app, event)
            value = app.FmaxSlider.Value;
            showaxes1(app);
            showaxes3(app);
        end

        % Value changed function: FminSlider
        function FminSliderValueChanged(app, event)
            value = app.FminSlider.Value;
            showaxes1(app);
            showaxes3(app);
        end

        % Value changed function: EMmaxSlider
        function EMmaxSliderValueChanged(app, event)
            value = app.EMmaxSlider.Value;
            showaxes2(app);
            showaxes3(app);
        end

        % Value changed function: EMminSlider
        function EMminSliderValueChanged(app, event)
            value = app.EMminSlider.Value;
            showaxes2(app);
            showaxes3(app);
        end

        % Button pushed function: matchafiducialButton
        function matchafiducialButtonPushed(app, event)
            if ~isempty(app.mmminfo.FIDSt{app.info.currentROI})
                currentFID=size(app.mmminfo.FIDSt{app.info.currentROI},1)+1;
            else
                currentFID=1;
            end
 
            linedPt = drawline(app.UIAxes3);
            posLine = linedPt.Position;
            delete(linedPt);
            centxTIRF = posLine(1, 1);
            centyTIRF = posLine(1, 2);
            centxEM = posLine(2, 1);
            centyEM = posLine(2, 2);
           
                
            app.mmminfo.FIDSt{app.info.currentROI}(currentFID,:)=[centxTIRF centyTIRF];
            app.mmminfo.FIDSem{app.info.currentROI}(currentFID,:)=[centxEM centyEM];
           
            
            app.removefiducialButton.Enable='on';
            app.manualrefinementButton.Enable='on';
            app.EditField.Enable='on';


            app.FidsLabel.Text=[num2str(currentFID) ' FIDS'];
            
            showfids(app);


        end

        % Button pushed function: removefiducialButton
        function removefiducialButtonPushed(app, event)
%             T=app.mmminfo.T{app.info.currentROI};
            
            
            %set(handles.remove,'Enable','off');
            badpoint=str2double(app.EditField.Value);
            
            FIDSt=app.mmminfo.FIDSt{app.info.currentROI};
            
            TFmat=ones(size(FIDSt,1),1);

            
            TFmat(badpoint)=0;
            
            app.mmminfo.FIDSt{app.info.currentROI}=app.mmminfo.FIDSt{app.info.currentROI}(logical(TFmat),:);
            
            app.mmminfo.FIDSem{app.info.currentROI}=app.mmminfo.FIDSem{app.info.currentROI}(logical(TFmat),:);
            

            manualrefinementButtonPushed(app);
        end

        % Button pushed function: manualrefinementButton
        function manualrefinementButtonPushed(app, event)
            EMimage = app.mmminfo.picHolder{app.info.currentROI,1};
            
            F1image=app.mmminfo.mmmF{app.info.currentROI, 1};
            %what are the coordinates you should be using to do the
            %transformation? FIDSt0 should always be referring to the
            %coordinates from the original image rather than the
            %coordinates on the diplayed image. FIDSt are the coordinates
            %on the existing present image.
            if isempty(app.mmminfo.mmmF{app.info.currentROI, 2})
                FIDSt0=app.mmminfo.FIDSt{app.info.currentROI};
                

            else
                T=app.mmminfo.T{app.info.currentROI};
                
                T1 = maketform('affine', T.T);

                % revert back to initial coords (don't need to for EM since already
                % in normal coords)
                FIDSt0 = tforminv(T1,app.mmminfo.FIDSt{app.info.currentROI});
                
            end
            
            if size(FIDSt0, 1)==1
                Yshift=app.mmminfo.FIDSem{app.info.currentROI}(1,2)-FIDSt0(1,2);
                Xshift=app.mmminfo.FIDSem{app.info.currentROI}(1,1)-FIDSt0(1,1);
                Tmat=[1 0 0; 0 1 0; Xshift Yshift 1];
                app.mmminfo.T{app.info.currentROI}=affine2d(Tmat);
            elseif size(FIDSt0, 1)==2
                app.mmminfo.T{app.info.currentROI} = fitgeotrans(FIDSt0,app.mmminfo.FIDSem{app.info.currentROI},'nonreflectivesimilarity');
            else
                app.mmminfo.T{app.info.currentROI} = fitgeotrans(FIDSt0,app.mmminfo.FIDSem{app.info.currentROI},'affine');
            end
            T=app.mmminfo.T{app.info.currentROI};
            tform1 = maketform('affine', T.T);
            
            transTIRF=tformfwd(tform1, FIDSt0);
            
            app.mmminfo.residuals{app.info.currentROI}=abs(transTIRF-app.mmminfo.FIDSem{app.info.currentROI});
            
            EMsize=size(EMimage);
            TIRFsize=size(F1image);
            Rin=imref2d(TIRFsize, [1,TIRFsize(2)],[1,TIRFsize(1)]);
            Rout = imref2d(EMsize, [1,EMsize(2)],[1,EMsize(1)]);
            if app.mmminfo.rgb==1
                imagedepth=size(F1image,3);
            else 
                imagedepth=1;
            end
            transformed=zeros(EMsize(1),EMsize(2),imagedepth);
            for ii=1:imagedepth
                transformed(:,:,ii)=imwarp(F1image(:,:,ii),Rin, T,'OutputView',Rout);
            end

            
            app.mmminfo.mmmF{app.info.currentROI, 2} = transformed;
            
            app.mmminfo.FIDSt{app.info.currentROI}=transTIRF; 
            
            
            app.UITable.Data=app.mmminfo.residuals{app.info.currentROI};
            if size(FIDSt0, 1)>3
                root=sqrt(app.mmminfo.residuals{app.info.currentROI}(:,1).^2+app.mmminfo.residuals{app.info.currentROI}(:,2).^2);
                worst=find(root==max(root(:)));
                
                app.EditField.Value=num2str(worst);
            else
                app.EditField.Value=num2str(1);
            end
            
            showaxes3(app);
        end

        % Button pushed function: SaveImagesButton
        function SaveImagesButtonPushed(app, event)
%             ask if you want to also save specified channels. 
            opts.Interpreter = 'tex';
            opts.Default = 'No';
            answer = questdlg('Would you like to crop other (large transformed) fluorescence images as well?','Others?','Yes','No', opts);
%             Handle response
            switch answer
                case 'Yes'
                    otherfilesQ = 1;
                    fileList = uipickfiles('FilterSpec','*.tif'); %pick Large files not the ones that were cropped previously.
                case 'No'
                    otherfilesQ = 0;
            end
            

%              First, save the fluorescence image that you already have.

            for jj=1:app.info.ROItotN
                
                if ~isempty(app.mmminfo.mmmF{jj, 2})
                
                    Fnewfilename=['MMMmatch', num2str(jj),'_', app.info.fileHolder{1,1}];
                    Fregsavename=[app.info.fileHolder{1,2}, Fnewfilename];
                    
    
                    if size(app.mmminfo.mmmF{jj, 2},3)==3  
                        
                        for iframe=1:3
                            if iframe==1
                                fullimage=app.mmminfo.mmmF{jj, 2}(:,:,iframe);
                                imwrite(uint16(app.mmminfo.mmmF{jj, 2}(241:(size(fullimage,1)-240),241:(size(fullimage,2)-241),iframe)),Fregsavename,'tif','Compression','none',...
                                              'WriteMode','overwrite');
                            else
                                fullimage=app.mmminfo.mmmF{jj, 2}(:,:,iframe);
                                imwrite(uint16(app.mmminfo.mmmF{jj, 2}(241:(size(fullimage,1)-240),241:(size(fullimage,2)-241),iframe)),Fregsavename,'tif','Compression','none',...
                                              'WriteMode','append');
                            end
                        end
                    else
                        
                        fullimage=app.mmminfo.mmmF{jj, 2};
                        imwrite(uint16(app.mmminfo.mmmF{jj, 2}(241:(size(fullimage,1)-240),241:(size(fullimage,2)-241))),Fregsavename,'tif','Compression','none',...
                                              'WriteMode','overwrite');
                    end
                elseif ~isempty(app.mmminfo.mmmF{jj, 1})
                    Fnewfilename=['MMMmatch', num2str(jj),'_', app.info.fileHolder{1,1}];
                    Fregsavename=[app.info.fileHolder{1,2}, Fnewfilename];
                    
    
                    if size(app.mmminfo.mmmF{jj, 1},3)==3  
                        
                        for iframe=1:3
                           if iframe==1
                                fullimage=app.mmminfo.mmmF{jj, 1}(:,:,iframe);
                                imwrite(uint16(app.mmminfo.mmmF{jj, 1}(241:(size(fullimage,1)-240),241:(size(fullimage,2)-241),iframe)),Fregsavename,'tif','Compression','none',...
                                              'WriteMode','overwrite');
                            else
                                fullimage=app.mmminfo.mmmF{jj, 1}(:,:,iframe);
                                imwrite(uint16(app.mmminfo.mmmF{jj, 1}(241:(size(fullimage,1)-240),241:(size(fullimage,2)-241),iframe)),Fregsavename,'tif','Compression','none',...
                                              'WriteMode','append');
                            end
                        end
                    else
                        
                        fullimage=app.mmminfo.mmmF{jj, 1};
                        imwrite(uint16(app.mmminfo.mmmF{jj, 1}(241:(size(fullimage,1)-240),241:(size(fullimage,2)-241))),Fregsavename,'tif','Compression','none',...
                                              'WriteMode','overwrite');
                    end
                end
            end
        
        
%             Now we need to consider the other channels and files
%             we will have to crop them again from the original large image
            halfcropsize=uint16(app.info.halfcropsize);
            totalshift=app.info.totalshift;
            %shift=uint16(app.info.shift);
            newpositions=uint16(double(app.info.mmmROIs)-double(totalshift)); 
            %assignin('base', 'originalpositions',app.info.mmmROIs);
            %assignin('base', 'newpositions',newpositions);
            
            %assignin('base', 'totalshift',totalshift);

            if otherfilesQ==1

                Nfiles=length(fileList); %how many files
                
                for ii=1:Nfiles
                    currentfile = imread(fileList{ii}); % get the specific file.
                    %assignin('base','currentfile', currentfile);
                    filesize=size(currentfile);   %get the number of frames
                    backslash=strfind(fileList{ii}, '\');  % find the backslash in the file name
                    if length(filesize)>2
                        imagedepth=size(currentfile,3);
                    else
                        imagedepth=1;
                    end
                    
                    for jj=1:app.info.ROItotN
                        if ~isempty(app.mmminfo.mmmF{jj, 1})
                            MMMimage=app.mmminfo.picHolder{jj,1};
                            %if jj==1
                                %assignin('base','MMMimage',MMMimage);
                            %end
                            MMMsized=double(imresize(MMMimage, (app.info.pixSize(2).*app.mmminfo.scalefactor)/(app.info.pixSize(1))));
                            %if jj==1
                            %assignin('base','MMMsized',MMMsized);
                            %end
                            whichrotfine=app.mmminfo.rotangle{jj};
                            xoffset=app.mmminfo.mmmoffsets{jj, 1};
                            yoffset=app.mmminfo.mmmoffsets{jj, 2};
                            REGsavename=[app.info.fileHolder{1,2},'MMMmatch', num2str(jj),'_', fileList{ii}((backslash(end)+1):end)];
                            if imagedepth==1
                                croppedimage=currentfile((newpositions(jj,2)-halfcropsize):(newpositions(jj,2)+halfcropsize),(newpositions(jj,1)-halfcropsize):(newpositions(jj,1)+halfcropsize));
                                
                                rotatedimage=imrotate(croppedimage, whichrotfine,'bilinear','crop');
                                finalsmallimage=rotatedimage(yoffset:yoffset+size(MMMsized,1)-1, xoffset:xoffset+size(MMMsized,2)-1);
                 
                                finallargeimage1=imresize(finalsmallimage,size(MMMimage), 'nearest');
%                                 if jj==1
%                                     assignin('base','croppedimage',croppedimage);
%                                     assignin('base','finalsmallimage',finalsmallimage);
%                                     assignin('base','finallargeimage1',finallargeimage1);
%                                 end
                                if isempty(app.mmminfo.mmmF{jj, 2})
                                    assignin('base','doingtransform','notransform');
                                    imwrite(uint16(finallargeimage1(241:(size(finallargeimage1,1)-240),241:(size(finallargeimage1,2)-241))),REGsavename,'tif','Compression','none',...
                                        'WriteMode','overwrite');
                                else
                                    assignin('base','doingtransform','yestransform');
                                    T=app.mmminfo.T{jj};
                	                Rin=imref2d(size(finallargeimage1), [1,size(finallargeimage1,2)],[1,size(finallargeimage1,1)]);
            		                Rout = imref2d(size(finallargeimage1), [1,size(finallargeimage1,2)],[1,size(finallargeimage1,1)]);
			                        finallargeimage=imwarp(finallargeimage1,Rin, T,'OutputView',Rout);
%                                     if jj==1
%                                         assignin('base','finallargeimage1',finallargeimage1);
%                                         assignin('base','finallargeimage',finallargeimage);
%                                     end
                                    imwrite(uint16(finallargeimage(241:(size(finallargeimage,1)-240),241:(size(finallargeimage,2)-241))),REGsavename,'tif','Compression','none',...
                                        'WriteMode','overwrite');
                                end
                            else
                                croppedimage=currentfile(newpositions(jj,2)-halfcropsize:newpositions(jj,2)+halfcropsize,newpositions(jj,1)-halfcropsize:newpositions(jj,1)+halfcropsize, :);
                                
                                for iframe=1:imagedepth
                                    rotatedimage=imrotate(croppedimage(:,:,iframe), whichrotfine,'bilinear','crop');
                                    finalsmallimage=rotatedimage(yoffset:yoffset+size(MMMsized,1)-1, xoffset:xoffset+size(MMMsized,2)-1);
                                    finallargeimage1=imresize(finalsmallimage,size(MMMimage), 'nearest');
                                    if isempty(app.mmminfo.mmmF{jj, 2})
                                        finallargeimage=finallargeimage1;
                                    else
                                        T=app.mmminfo.T{jj};
            	                        Rin=imref2d(size(finallargeimage1), [1,size(finallargeimage1,2)],[1,size(finallargeimage1,1)]);
            		                    Rout = imref2d(size(finallargeimage1), [1,size(finallargeimage1,2)],[1,size(finallargeimage1,1)]);
			                            finallargeimage=imwarp(finallargeimage1,Rin, T,'OutputView',Rout);
                                    end
                                    if iframe==1
                                        imwrite(uint16(finallargeimage(241:(size(finallargeimage,1)-240),241:(size(finallargeimage,2)-241))),REGsavename,'tif','Compression','none',...
                                            'WriteMode','overwrite');
                                        %             imwrite(uint16(greenmoviecut(:,:,iframe)),FullFileNameGw,'tif','Compression','none',...
                                        %                 'WriteMode','overwrite');
                                    else
                                        imwrite(uint16(finallargeimage(241:(size(finallargeimage,1)-240),241:(size(finallargeimage,2)-241))),REGsavename,'tif','Compression','none',...
                                            'WriteMode','append');
                                        %             imwrite(uint16(greenmoviecut(:,:,iframe)),FullFileNameRw,'tif','Compression','none',...
                                    %                 'WriteMode','append');
                                    end
                                end
                            end
                            
                        end %if isempty
                    end
                end
%                 clear('savedimage');
            end
            warning('Your images have been saved!');
            appdatainfo=app.info;
            appdatapicHolder=app.picHolder;
            appdatammminfo=app.mmminfo;
            save([app.info.fileHolder{1,2}, 'appdatapicHolder.mat'],"appdatapicHolder");
            save([app.info.fileHolder{1,2}, 'appdatainfo.mat'],"appdatainfo");
            save([app.info.fileHolder{1,2}, 'appdatammminfo.mat'],"appdatammminfo",'-v7.3');
            warning('Your data have been saved!');


        end

        % Button pushed function: PicknewtomopositionButton
        function PicknewtomopositionButtonPushed(app, event)
            if isfield(app.mmminfo,'tomopos')
                currentTOMO=size(app.mmminfo.tomopos,1)+1;
            else
                currentTOMO=1;
            end
            
            Pt = drawpoint(app.UIAxes3);
            posPt = Pt.Position;
            Pt.Label=['tomo ' num2str(currentTOMO)];                
            app.mmminfo.tomopos(currentTOMO,2:3)=posPt;
            app.mmminfo.tomopos(currentTOMO,1)=app.info.currentROI;
            clear('randomnums');
            randomnums=randi([0 9],1,9);
            randomnumstring = sprintf('%d', randomnums);
            app.mmminfo.tomopos(currentTOMO,4)=str2num(randomnumstring);
            app.mmminfo.assignedNav{currentTOMO,1}=app.CurrentMapIDDropDown.Value;

            app.UITable2.Data=cat(2,num2cell(app.mmminfo.tomopos),app.mmminfo.assignedNav);
            app.removeposButton.Enable='on';
            app.UITable2.RowName='numbered';
            app.EditField_2.Value=currentTOMO;
            app.UploadNavFileButton.Enable='on';


        end

        % Button pushed function: removeposButton
        function removeposButtonPushed(app, event)
            badpoint=app.EditField_2.Value;
            TFmat=ones(size(app.mmminfo.tomopos,1),1);

            
            TFmat(badpoint)=0;
            app.mmminfo.tomopos=app.mmminfo.tomopos(logical(TFmat),:);

            app.mmminfo.assignedNav=app.mmminfo.assignedNav(logical(TFmat),:);
            
            app.EditField_2.Value=size(app.mmminfo.tomopos,1);

            showaxes3(app);
            
%             imshow(app.info.overlayimage,[],'Parent',app.UIAxes3);      
%             for ii=1:size(app.info.mmmROIs,1)
%                Pt=drawpoint(app.UIAxes3, 'Position',app.info.mmmROIs(ii,:)); 
%                Pt.Label=['ROI ' num2str(ii)];
%             end
%             app.createMMMNavButton.Enable='on';
        end

        % Button pushed function: UploadNavFileButton
        function UploadNavFileButtonPushed(app, event)

            %The MRC and mdoc file need to be present when you use
            %this navigator file. This is how it will calculate approximate
            %stage coordinates. 
            [file1NAV,path1NAV] = uigetfile('*.nav', 'Please choose the navigator file that includes all maps used here.', app.info.fileHolder{4, 2});
            
            %Goal: we need to find the MMM maps that are applicable and
            %then create new items that are linked to the applicable maps.
            %First, find all maps in the existing Navigator file. 
            [filepath,rootnameNAV,ext] = fileparts([path1NAV file1NAV]);
%             status=copyfile([path1NAV file1NAV], [path1NAV rootnameNAV '_wTomoPicks.nav']);
%             while status==0
%                 wait(0.5)
%             end
            app.mmminfo.Navpathfile=[path1NAV rootnameNAV];
            fidNAV  = fopen([path1NAV rootnameNAV '.nav'],'r+');
            fNAV=fread(fidNAV,'*char')';
            %fprintf(fidNAV,'note = hole');
            fclose(fidNAV);
            whatNAV=regexp(fNAV,'\n','split'); %I think "readlines" also does this in one line of code instead of two.


            itemlineALL = (find(contains(whatNAV,'Item'))); %This is the index of the rows that have the word item in them. 
            itemcats=cell(length(whatNAV),1);
            for ii=1:length(itemlineALL)
                itemLOI=whatNAV{itemlineALL(ii)};
                
                lastbracket=strfind(itemLOI,']');
                equalsign=strfind(itemLOI,'=');
                if ii<length(itemlineALL)
                    number1=itemlineALL(ii);
                    number2=itemlineALL(ii+1)-2;
                    for jj=number1:number2
                        itemcats{jj, 1}=(itemLOI((equalsign+2):(lastbracket-1)));
                    end
                    %itemcats is just a map of what rows of information
                    %belong to which item. Should have the same length as
                    %whatNAV and just contains the item number that each
                    %line of information belongs to. 
                else
                    number1=itemlineALL(ii);
                    number2=size(itemcats,1);
                    %assignin('base','test2',itemLOI((equalsign+2):(lastbracket-1)));
                    %assignin('base','number2',number2);
                    for jj=number1:number2
                        itemcats{jj, 1}=(itemLOI((equalsign+2):(lastbracket-1)));
                    end
                end
            end
            %assignin('base','itemcats',itemcats);
            whichitemsaremonts = itemcats(contains(whatNAV,'MapMontage = 1')); %This should be a list of items that are montages.
            %assignin('base','whichitemsaremonts',whichitemsaremonts);
            app.mmminfo.notesID{length(whichitemsaremonts), 3}=[];
            app.mmminfo.optionarray{length(whichitemsaremonts)}=[];
            

            %I would like to collect the filename, section number (these are both listed in the notes section), and the
            %MapID from each montage
            if isfield(app.mmminfo, 'optionarray')
                app.mmminfo=rmfield(app.mmminfo,'optionarray');
            end
            

            for ii=1:length(whichitemsaremonts)
                thismont=strcmp(itemcats,whichitemsaremonts(ii)); %should be true in places where the information applies to the specific montage of interest
                notelines=contains(whatNAV,'Note');
                %assignin('base','thismont',thismont);
                %assignin('base','notelines',notelines);
                
                
                %assignin('base','whatNAV',whatNAV);
                noteLOI=whatNAV{thismont'&notelines};
                equalspace=strfind(noteLOI,'= ');
                app.mmminfo.notesID{ii, 1}=noteLOI((equalspace+2):end);
                Secfind=strfind(noteLOI,'Sec');
                spacedash=strfind(noteLOI,' -');
                app.mmminfo.notesID{ii, 1}=noteLOI((equalspace+2):end);
                app.mmminfo.notesID{ii, 3}=str2double(noteLOI((Secfind+4):(spacedash-1)));
                mapidlines=contains(whatNAV,'MapID');
                mapidLOI=whatNAV{thismont'&mapidlines};
                equalspace=strfind(mapidLOI,'= ');
                app.mmminfo.notesID{ii, 2}=str2double(mapidLOI((equalspace+2):end));
                app.mmminfo.optionarray{ii}=[app.mmminfo.notesID{ii, 1}, ' ID',mapidLOI((equalspace+2):end)];
            end

            
            app.CurrentMapIDDropDown.Items=app.mmminfo.optionarray;
            %Now ask if there is a region that matches the best with the
            %current ROI. For example, if the Notes have regions that are
            %numbered 1:N then automatically bring up the default value as
            %the matching section number in the Navigator File.


            sections= cell2mat(app.mmminfo.notesID(:, 3))+1;
            whichmont=find(sections==app.info.currentROI, 1, 'last');
            if whichmont>0
                app.CurrentMapIDDropDown.Value=app.mmminfo.optionarray{whichmont};
            else
                app.CurrentMapIDDropDown.Value=app.mmminfo.optionarray{2};
            end
                      
            %Put the notes and MapID into a drop down list so that users can
            %pick which navigator item is appropriate for their tomo to be
            %associated with. 


        end

        % Button pushed function: SaveNavFileButton
        function SaveNavFileButtonPushed(app, event)
            %      add to the end of the current Nav file
%           All of the tomos from each map need to be assigned the same group ID.
%           So, let's take all of the DrawnIDs and subtract 111 in order to
%           make it the groupID. This makes it so that if you run the
%           program multiple times and create many Nav files, it will
%           always choose the same groupID.
            %First step is to identify all of the unique Drawn IDs in the
            %list of tomos. then go through each category and  
            %%%
            
            
            %Goal: we need to find the MMM maps that are applicable and
            %then create new items that are linked to the applicable maps.
            %First, find all maps in the existing Navigator file. 
            [filepath,rootnameNAV,ext] = fileparts([app.mmminfo.Navpathfile '.nav']);
            status=copyfile([app.mmminfo.Navpathfile '.nav'], [app.mmminfo.Navpathfile '_wTomoPicks.nav']);
            while status==0
                wait(0.5)
            end
            fidNAV  = fopen([app.mmminfo.Navpathfile '_wTomoPicks.nav'],'r+');
            fNAV=fread(fidNAV,'*char')';
            fprintf(fidNAV,'\r\n');
            
            whatNAV=regexp(fNAV,'\n','split');

            %%%

            %%%
            %get all of the item numbers in the existing Nav file so tha
            %tyou know which number is the greatest so you can start
            %numbering after the largest number. 
            itemlineALL = (find(contains(whatNAV,'Item'))); %This is the index of the rows that have the word item in them. 
            %itemN=cell(length(itemlineALL),1);
            firstitemnumber=zeros(1,length(itemlineALL));
            for ii=1:length(itemlineALL)
                %itemnumbers=[];
                itemLOI=whatNAV{itemlineALL(ii)};
                
                lastbracket=strfind(itemLOI,']');
                equalsign=strfind(itemLOI,'=');
                itemstring=(itemLOI((equalsign+2):(lastbracket-1)));
                %assignin('base','itemstring',itemstring);
                itemnumbers = regexp(itemstring,'\d*','Match');
                %assignin('base','itemnumbers',itemnumbers);
                firstitemnumber(ii)=str2double(itemnumbers{1,1});
                
            end
            %assignin('base','firstitemnumber', firstitemnumber);
            maxitemN=max(firstitemnumber(:))+1;
            %Here is a problem
            %maxitemN=50;
            list=app.mmminfo.assignedNav;
           
            assignedNavcat=categorical(list);
            uniquecats=unique(assignedNavcat);


            itemnumber=1;
            for ii=1:length(uniquecats)
                groupIDmat(ii, :)=randi([0 9],1,9);
                whichones=find(assignedNavcat==uniquecats(ii));
                
                for jj=1:length(whichones)
                    currentTOMO=whichones(jj);
                    notetext=app.mmminfo.assignedNav{currentTOMO,1};
                    IDloc=strfind(notetext,'ID');
                    drawnID=str2num(notetext((IDloc+2):end));
                    %%%problem here! The item number neeeds to update
                    %%%otherwise it starts again with every new MMM image. 
                    %Here, I think I have fixed the scaling problem. This
                    %needs to be checked. 
                    fprintf(fidNAV,'[Item = %i]\r\nColor = 0\r\nCoordsInAliMont = %.3f %.3f %.2f', (itemnumber+maxitemN), app.mmminfo.tomopos(currentTOMO,2).*app.mmminfo.scalefactor, app.mmminfo.scalefactor.*(size(app.mmminfo.picHolder{app.mmminfo.tomopos(currentTOMO,1),1},1)-app.mmminfo.tomopos(currentTOMO,3)), 0);
                    fprintf(fidNAV,'\r\nNumPts = 1\r\nRegis = 1\r\nType = 0\r\nGroupID = %s\r\n', sprintf('%d', groupIDmat));
                    fprintf(fidNAV,'DrawnID = %i\r\nBklshXY = 0 0\r\n', drawnID);
                    fprintf(fidNAV,'MapID = %s\r\nPtsX = %.3f\r\nPtsY = %.3f\r\n\r\n', num2str(app.mmminfo.tomopos(currentTOMO,4)), app.mmminfo.tomopos(currentTOMO,2).*app.mmminfo.scalefactor, app.mmminfo.scalefactor.*(size(app.mmminfo.picHolder{app.mmminfo.tomopos(currentTOMO,1),1},1)-app.mmminfo.tomopos(currentTOMO,3)));    
                    itemnumber=itemnumber+1;
                end
            end
            fclose(fidNAV);
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            MMMimage=app.mmminfo.picHolder{app.info.currentROI,1};
            LMMimage=app.info.croppedEM(:,:,app.info.currentROI);


            whichrotfine=0;
            app.mmminfo.rotangle{app.info.currentROI}=whichrotfine;
            
            yoffset = floor(size(LMMimage, 1)./2)-floor(size(MMMcropped,1)./2);
            xoffset = floor(size(LMMimage, 2)./2)-floor(size(MMMcropped,2)./2);
            app.mmminfo.mmmoffsets{app.info.currentROI, 1}=xoffset;
            app.mmminfo.mmmoffsets{app.info.currentROI, 2}=yoffset;

            %Yoffset and xoffset define the position on the LMMimage that
            %matches the upper left pixel on the MMMsized 
            
            
            if app.mmminfo.rgb==1
                Fimage=app.info.croppedF(:,:,:,app.info.currentROI);
                imagedepth=3;
                Fimagefinal=zeros(size(MMMimage,1),size(MMMimage,2),imagedepth);
                for jj=1:3
                    Fimagerot=Fimage(:,:,jj);
                    Fimagecropped=Fimagerot(yoffset:(yoffset+size(MMMsized,1)-1), xoffset:(xoffset+size(MMMsized,2)-1));
                    Fimagefinal(:,:,jj)=imresize(Fimagecropped,size(MMMimage), 'nearest');
                    
                end
                app.mmminfo.mmmF{app.info.currentROI,1}=Fimagefinal;
            else 
                Fimage=app.info.croppedF(:,:,app.info.currentROI);
                imagedepth=1;
                Fimagerot=Fimage;
                Fimagecropped=Fimagerot(yoffset:yoffset+size(MMMsized,1)-1, xoffset:xoffset+size(MMMsized,2)-1);
                Fimagefinal=imresize(Fimagecropped,size(MMMimage), 'nearest');
                app.mmminfo.mmmF{app.info.currentROI,1}=Fimagefinal;
            end
            app.mmminfo.fileHolder{app.info.currentROI, 2}=[app.mmminfo.fileHolder{app.info.currentROI, 1}, ' / ROI', num2str(app.info.currentROI)];
            
            app.matchafiducialButton.Enable='on';

            app.UITable.Enable='on';
            app.PicknewtomopositionButton.Enable='on';
            
            showaxes3(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.gotoROIButton = uibutton(app.UIFigure, 'push');
            app.gotoROIButton.ButtonPushedFcn = createCallbackFcn(app, @gotoROIButtonPushed, true);
            app.gotoROIButton.Text = 'go to ROI';
            app.GoToEditField = uieditfield(app.UIFigure, 'numeric');
            app.GoToEditField.Value = 2;
            app.openmatchinghigherresolutionmapButton = uibutton(app.UIFigure, 'push');
            app.openmatchinghigherresolutionmapButton.ButtonPushedFcn = createCallbackFcn(app, @openmatchinghigherresolutionmapButtonPushed, true);
            app.openmatchinghigherresolutionmapButton.Text = 'open matching higher resolution map';
            app.autocorrelateButton = uibutton(app.UIFigure, 'push');
            app.autocorrelateButton.ButtonPushedFcn = createCallbackFcn(app, @autocorrelateButtonPushed, true);
            app.autocorrelateButton.Text = 'auto-correlate';
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.manualrefinementButton = uibutton(app.UIFigure, 'push');
            app.manualrefinementButton.ButtonPushedFcn = createCallbackFcn(app, @manualrefinementButtonPushed, true);
            app.manualrefinementButton.Text = {'manual '; 'refinement'};
            app.EMminSliderLabel = uilabel(app.UIFigure);
            app.EMminSliderLabel.Text = 'EMmin';
            app.EMminSlider = uislider(app.UIFigure);
            app.EMminSlider.Limits = [0 255];
            app.EMminSlider.MajorTicks = [];
            app.EMminSlider.ValueChangedFcn = createCallbackFcn(app, @EMminSliderValueChanged, true);
            app.EMminSlider.MinorTicks = [];
            app.EMmaxSliderLabel = uilabel(app.UIFigure);
            app.EMmaxSliderLabel.Text = 'EMmax';
            app.EMmaxSlider = uislider(app.UIFigure);
            app.EMmaxSlider.Limits = [0 255];
            app.EMmaxSlider.MajorTicks = [];
            app.EMmaxSlider.ValueChangedFcn = createCallbackFcn(app, @EMmaxSliderValueChanged, true);
            app.EMmaxSlider.MinorTicks = [];
            app.EMmaxSlider.Value = 255;
            app.FminSliderLabel = uilabel(app.UIFigure);
            app.FminSliderLabel.Text = 'Fmin';
            app.FminSlider = uislider(app.UIFigure);
            app.FminSlider.Limits = [0 255];
            app.FminSlider.MajorTicks = [];
            app.FminSlider.ValueChangedFcn = createCallbackFcn(app, @FminSliderValueChanged, true);
            app.FminSlider.MinorTicks = [];
            app.FminSlider.Value = 150;
            app.FmaxSliderLabel = uilabel(app.UIFigure);
            app.FmaxSliderLabel.Text = 'Fmax';
            app.FmaxSlider = uislider(app.UIFigure);
            app.FmaxSlider.Limits = [0 255];
            app.FmaxSlider.MajorTicks = [];
            app.FmaxSlider.ValueChangedFcn = createCallbackFcn(app, @FmaxSliderValueChanged, true);
            app.FmaxSlider.MinorTicks = [];
            app.FmaxSlider.Value = 255;
            app.matchafiducialButton = uibutton(app.UIFigure, 'push');
            app.matchafiducialButton.ButtonPushedFcn = createCallbackFcn(app, @matchafiducialButtonPushed, true);
            app.matchafiducialButton.Text = {'match a '; 'fiducial'};
            app.removefiducialButton = uibutton(app.UIFigure, 'push');
            app.removefiducialButton.ButtonPushedFcn = createCallbackFcn(app, @removefiducialButtonPushed, true);
            app.removefiducialButton.Text = {'remove '; 'fiducial'};
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'ResX'; 'ResY'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SaveImagesButton = uibutton(app.UIFigure, 'push');
            app.SaveImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImagesButtonPushed, true);
            app.SaveImagesButton.Text = {'Save '; 'Images'};
            app.UploadNavFileButton = uibutton(app.UIFigure, 'push');
            app.UploadNavFileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadNavFileButtonPushed, true);
            app.UploadNavFileButton.Text = 'Upload Nav File';
            app.PicknewtomopositionButton = uibutton(app.UIFigure, 'push');
            app.PicknewtomopositionButton.ButtonPushedFcn = createCallbackFcn(app, @PicknewtomopositionButtonPushed, true);
            app.PicknewtomopositionButton.Text = {'Pick new '; 'tomo position'};
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'ROI'; 'Tomo pos X'; 'Tomo pos Y'; 'generated MapID'; 'Note / DrawnID'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = [false false false true];
            app.FidsLabel = uilabel(app.UIFigure);
            app.FidsLabel.Text = '0 Fids';
            app.removeposButton = uibutton(app.UIFigure, 'push');
            app.removeposButton.ButtonPushedFcn = createCallbackFcn(app, @removeposButtonPushed, true);
            app.removeposButton.Text = {'remove '; 'pos'};
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.SaveNavFileButton = uibutton(app.UIFigure, 'push');
            app.SaveNavFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNavFileButtonPushed, true);
            app.SaveNavFileButton.Text = {'Save '; 'Nav File'};
            app.CurrentMapIDDropDownLabel = uilabel(app.UIFigure);
            app.CurrentMapIDDropDownLabel.Text = 'Current MapID';
            app.CurrentMapIDDropDown = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = autocorrMMM(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end