classdef lmmalignapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        adjalignmentofsquaresButton  matlab.ui.control.Button
        EditField_2                  matlab.ui.control.NumericEditField
        MMMremoveButton              matlab.ui.control.Button
        MMMnum                       matlab.ui.control.Label
        pickMMMROIButton             matlab.ui.control.Button
        resetpicksButton             matlab.ui.control.Button
        transformotherButton         matlab.ui.control.Button
        createMMMNavButton           matlab.ui.control.Button
        transformButton              matlab.ui.control.Button
        newlandmarkButton            matlab.ui.control.Button
        submitButton                 matlab.ui.control.Button
        rotate90Button               matlab.ui.control.Button
        FluorpickButton              matlab.ui.control.Button
        EMpickButton                 matlab.ui.control.Button
        EditField                    matlab.ui.control.NumericEditField
        Label                        matlab.ui.control.Label
        removeButton                 matlab.ui.control.Button
        testButton                   matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        fluormaxSlider               matlab.ui.control.Slider
        fluormaxLabel                matlab.ui.control.Label
        fluorminSlider               matlab.ui.control.Slider
        fluorminLabel                matlab.ui.control.Label
        EMminSlider                  matlab.ui.control.Slider
        EMminLabel                   matlab.ui.control.Label
        EMmaxSlider                  matlab.ui.control.Slider
        EMmaxLabel                   matlab.ui.control.Label
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        picHolder  %This is meant to store primary fluorescence, secondary fluorescence, white light, EM respectively along the first axis. It holds the raw data, scaled to EM size, transformed, and 5x smaller transformed on second axis.
        info % Description
        
    end
    
    methods (Access = private)
        
        function showall(app)
            

            EMmin=app.EMminSlider.Value;
            EMmax=app.EMmaxSlider.Value;
           
            Fmin=app.fluorminSlider.Value;
            Fmax=app.fluormaxSlider.Value;

            disp('during showall 1');

            %which version are we displaying? I think we want to display raw in the
            %first two axes. But transformed in the third axes.
            EMdisplay=255.0.*((double(app.picHolder{4,1})-EMmin)./(EMmax-EMmin));
            EMdisplay(EMdisplay>255)=255;
            
            %if you haven't pressed snaptogrid at all, display only the raw images.
            if app.info.usesnap==0
                FL1display=255.0.*((double(app.picHolder{1,1})-Fmin)./(Fmax-Fmin));
                FL1display(FL1display>255)=255;

            %if you have pressed snaptogrid, display the scaled and rotated image in axes2
            else
                FL1display=255.0.*((double(app.picHolder{1,2})-Fmin)./(Fmax-Fmin));
                FL1display(FL1display>255)=255;
            
            end
            
            
            axis1image=cat(3,uint8(EMdisplay),uint8(EMdisplay),uint8(EMdisplay));
            assignin('base','axis1image', axis1image);
            assignin('base','axis1imageb', axis1image);
            disp('during showall');
            if size(FL1display,3)==3
                axis2image=uint8(FL1display);
                
            else
                axis2image=cat(3,uint8(FL1display),uint8(FL1display).*0,uint8(FL1display));
            end
            
            %if you have done a transformation using points of alignment, display
            %picHolder{1,3} overlayed with the EM image on axes3.
            
            if ~isempty(app.picHolder{1,3})
                F1Tdisplay=255.0.*((double(app.picHolder{1,3})-Fmin)./(Fmax-Fmin));
                F1Tdisplay(F1Tdisplay>255)=255;   
                
                %if you haven't done any transformation with points of alignment yet
                %but you did do a snaptogrid, display the auto-aligned fluorescence
                %image overlayed onto the EM image in axes 3.
                if size(F1Tdisplay,3)==3
                    F1TdisplayRGB=F1Tdisplay;
                else
                    F1TdisplayRGB=cat(3,uint8(F1Tdisplay),uint8(F1Tdisplay).*0,uint8(F1Tdisplay));
                end
            elseif ~isempty(app.picHolder{1,4})   
                F1Tdisplay=255.0.*((double(app.picHolder{1,4})-Fmin)./(Fmax-Fmin));
                F1Tdisplay(F1Tdisplay>255)=255;  
                if size(F1Tdisplay,3)==3
                    F1TdisplayRGB=F1Tdisplay;
                else
                    F1TdisplayRGB=cat(3,uint8(F1Tdisplay),uint8(F1Tdisplay).*0,uint8(F1Tdisplay));
                end
            end


            imshow(axis1image,[],'Parent',app.UIAxes);
            imshow(axis2image,[],'Parent',app.UIAxes2);
            
            if ~isempty(app.picHolder{1,3}) || ~isempty(app.picHolder{1,4})
                axis3image=uint8(0.5.*axis1image)+uint8(F1TdisplayRGB);
                app.info.overlayimage=axis3image;
                
                imshow(axis3image,[],'Parent',app.UIAxes3); 
                disp('inimshowloop');
                assignin('base','axis3image',axis3image);
            end
            
            
            if isfield(app.info,'FIDSt') 


                hold(app.UIAxes3,'on')
                scatter(app.UIAxes3,app.info.FIDSem(:,1),app.info.FIDSem(:,2),'w.');
                scatter(app.UIAxes3,app.info.FIDSt(:,1),app.info.FIDSt(:,2),'b.');
                for i = 1:size(app.info.FIDSem, 1)
                    text(app.UIAxes3, app.info.FIDSem(i,1) + 20, app.info.FIDSem(i,2) + 20, ...
                        num2str(i), 'FontSize',12, 'Color','white');
                end
                hold(app.UIAxes3,'off')
            end


            %I don't know yet where this information should be stored.
            %Where will the polygonsn be stored when they are made?
            if isfield(app.info, 'poly1')
                poly1=app.info.poly1;
                poly1pos=app.info.poly1pos;
                drawpolyline(app.UIAxes, 'Position', poly1pos);
            end
            if isfield(app.info, 'poly2')
                poly2=app.info.poly2;
                poly2pos=app.info.poly2pos;
                drawpolyline(app.UIAxes2,'Position', poly2pos);
            end
        end
        
        function showgold(app)
            
            if isfield(app.info,'FIDSt') 


                hold(app.UIAxes3,'on')
                scatter(app.UIAxes3,app.info.FIDSem(:,1),app.info.FIDSem(:,2),'w.');
                scatter(app.UIAxes3,app.info.FIDSt(:,1),app.info.FIDSt(:,2),'b.');
                for i = 1:size(app.info.FIDSem, 1)
                    text(app.UIAxes3, app.info.FIDSem(i,1) + 20, app.info.FIDSem(i,2) + 20, ...
                        num2str(i), 'FontSize',12, 'Color','white');
                end
                hold(app.UIAxes3,'off')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)


            if isempty(varargin)
                %read in images
                app.info.fileHolder = cell(4,3);
                [app.info.fileHolder{4, 1}, app.info.fileHolder{4, 2}, app.info.fileHolder{4, 3}] = uigetfile(['*'],'Pick your EM map');
                [app.info.fileHolder{1, 1}, app.info.fileHolder{1, 2}, app.info.fileHolder{1, 3}] = uigetfile([app.info.fileHolder{4, 2}, '*'],'Pick your fluorescence map');
                
                app.picHolder=cell(4,4);
                app.picHolder{4,1} = imread([app.info.fileHolder{4, 2}, app.info.fileHolder{4, 1}]);
                app.picHolder{1,1} = imread([app.info.fileHolder{1, 2}, app.info.fileHolder{1, 1}]);
                %ask the user what the pixel sizes are.
%                 prompt = {'Enter EM pixel size (nm):','Enter fluorescence pixel size (nm):'};
%                 dlgtitle = 'Input';
%                 dims = [1 35];
%                 definput = {'357.5','130'};
%                 answer = inputdlg(prompt,dlgtitle,dims,definput);
%                 app.info.pixSize(4)=str2double(answer{1});
%                 app.info.pixSize(1)=str2double(answer{2});
                app.info.imageType=[1 0 0 1];


                %set sliders
                %set sliders to min and max values of each image
                %set sliders for EM image (min, max, and best guess values)
                
            else
                app.picHolder=varargin{1};
                app.info=varargin{2};
            end
            
            %Assign image titles
            app.UIAxes.Title.String=strrep(app.info.fileHolder{4, 1},'_','\_');
            app.UIAxes2.Title.String=strrep(app.info.fileHolder{1, 1},'_','\_');
            
            EMimage=app.picHolder{4,1};

            EMlimits=[double(min(min(EMimage))), double(max(max(EMimage)))];
            
            app.EMmaxSlider.Limits=EMlimits;
            app.EMminSlider.Limits=EMlimits;
            app.EMmaxSlider.Value=double(max(max(EMimage)));
            app.EMminSlider.Value=double(min(min(EMimage)));
            
            FLUORimage=app.picHolder{1,1};
            %The image could be RGB or uint16. Just scale min to max. 
            
            MINf=double(min(FLUORimage(:)));
            MAXf=double(max(FLUORimage(:)));
            
            app.fluormaxSlider.Limits=[MINf, MAXf];
            app.fluorminSlider.Limits=[MINf, MAXf];
            app.fluormaxSlider.Value=MAXf;
            app.fluorminSlider.Value=MINf;
            
            % % set active buttons etc.
            % turn buttons on/off and save values NOT DONE YET
            
            app.removeButton.Enable='off';
            app.testButton.Enable='off';
            app.newlandmarkButton.Enable='off';
            app.FluorpickButton.Enable='off';
            app.transformButton.Enable='off';
            app.transformotherButton.Enable='off';            
            app.createMMMNavButton.Enable='on';
            %app.flipButton.Enable='off';
            app.info.usesnap=0;
            app.info.rotation=0;
            %app.info.flip=0;
            disp('before showall');
            showall(app);
            disp('after showall');

        end

        % Button pushed function: FluorpickButton
        function FluorpickButtonPushed(app, event)

            if ~isfield(app.UIAxes2, 'poly2')
                poly2 = drawpolyline(app.UIAxes2);
            end
            poly2pos=poly2.Position;
            app.info.poly2=poly2;
            app.info.poly2pos=poly2pos;
            app.FluorpickButton.Enable='off';
            app.submitButton.Enable='on';


        end

        % Button pushed function: EMpickButton
        function EMpickButtonPushed(app, event)
            if ~isfield(app.UIAxes, 'poly1')
                poly1 = drawpolyline(app.UIAxes);
            end
            poly1pos=poly1.Position;
            app.FluorpickButton.Enable='on';
            app.EMpickButton.Enable='off';
            app.info.poly1=poly1;
            app.info.poly1pos=poly1pos;
        end

        % Button pushed function: submitButton
        function submitButtonPushed(app, event)
            

            % get the data

            posEM = app.info.poly1pos;
            posT = app.info.poly2pos;
            %picsUsed=app.info.picsUsed;
            usesnap=app.info.usesnap;
            EMimage = app.picHolder{4,1};
            
            
            % perform the transformation
            lenEM = length(posEM);
            lenT = length(posT);

            % Find number of points.
            fidsN=length(posT);
            
            app.info.T = fitgeotrans(posT(1:fidsN, 1:2),posEM(1:fidsN, 1:2),'nonreflectivesimilarity');
            
            app.info.Rout = imref2d(size(EMimage));
            
            if usesnap==0
                app.picHolder{1,3} = imwarp(app.picHolder{1,1}, app.info.T,'OutputView',app.info.Rout);
                     
            else
                app.picHolder{1,3} = imwarp(app.picHolder{1,2}, app.info.T,'OutputView',app.info.Rout);
            
            end
            
            
            app.newlandmarkButton.Enable='on';
            
            if isfield(app.info, 'FIDSt')
                app.info=rmfield(app.info,'FIDSt');
                app.info=rmfield(app.info,'FIDSem');
            end


            disp('submit1');
            showall(app);
            disp('submit2');
            app.testButton.Enable='off';
            app.removeButton.Enable='off';
            app.transformButton.Enable='off';
            
%             app.UITable.Data=cell(size(app.UITable.Data));

        end

        % Button pushed function: newlandmarkButton
        function newlandmarkButtonPushed(app, event)
            
            
            if isfield(app.info,'FIDSt')
                currentFID=size(app.info.FIDSt,1)+1;
            else
                currentFID=1;
            end
            
            linedPt = drawline(app.UIAxes3);
            posLine = linedPt.Position;
            delete(linedPt);
            centxTIRF = posLine(1, 1);
            centyTIRF = posLine(1, 2);
            centxEM = posLine(2, 1);
            centyEM = posLine(2, 2);
           
                
            app.info.FIDSt(currentFID,:)=[centxTIRF centyTIRF];
            app.info.FIDSem(currentFID,:)=[centxEM centyEM];


            if currentFID>2
                app.testButton.Enable='on';
            else
                app.testButton.Enable='off';
            end
            
            app.removeButton.Enable='on';
        
            app.EditField.Value=currentFID;
            
            showgold(app);
            
        end

        % Button pushed function: removeButton
        function removeButtonPushed(app, event)


            T=app.info.T;
            
            
            %set(handles.remove,'Enable','off');
            badpoint=app.EditField.Value;
            TFmat=ones(size(app.info.FIDSt,1),1);

            
            TFmat(badpoint)=0;
            app.info.FIDSt=app.info.FIDSt(logical(TFmat),:);
            app.info.FIDSem=app.info.FIDSem(logical(TFmat),:);
            %find what the coordinates would be on the orginal image.
            %
            T1 = maketform('affine', T.T);
            %
            
            FIDSt0 = tforminv(T1,app.info.FIDSt);
            
            tform = fitgeotrans(FIDSt0,app.info.FIDSem,'affine');
            tform1 = maketform('affine', tform.T);
            
            transTIRF=tformfwd(tform1, FIDSt0);
            residuals=abs(transTIRF-app.info.FIDSem);


            app.transformButton.Enable='on';
            
            root=sqrt(residuals(:,1).^2+residuals(:,2).^2);
            worst=find(root==max(root(:)));
            app.EditField.Value=worst;


            app.info.residuals=residuals;
            app.info.FIDSt0=FIDSt0;

            app.info.tform=tform;
            app.UITable.Data=residuals;
            testButtonPushed(app);
        end

        % Button pushed function: testButton
        function testButtonPushed(app, event)
            

            EMimage = app.picHolder{4,1};
            usesnap=app.info.usesnap;
            
            %what image should you transform? If you haven't pressed snap to grid, you
            %should be transforming the original image.
            if usesnap==0
                F1image=app.picHolder{1,1};
                
                %if you have pressed snaptogrid, you should be transforming from the
                %sized and rotated image. 
            else
                F1image=app.picHolder{1,4};
                
            end
            
            %if isappdata(handles.axes3, 'T')
            if ~isempty(app.picHolder{1,3})
                T=app.info.T;
                
                T1 = maketform('affine', T.T);
                %app.info.T=T;
                %Just changed this to T1. 
                % revert back to initial coords (don't need to for EM since already
                % in normal coords)
                app.info.FIDSt0 = tforminv(T1,app.info.FIDSt);
            else
                app.info.FIDSt0=app.info.FIDSt;
            
            end
            
            app.info.tform = fitgeotrans(app.info.FIDSt0,app.info.FIDSem,'affine');
            app.info.tform1 = maketform('affine', app.info.tform.T);
            
            transTIRF=tformfwd(app.info.tform1, app.info.FIDSt0);

            app.info.residuals=abs(transTIRF-app.info.FIDSem);
            
            EMsize=size(EMimage);
            TIRFsize=size(F1image);
            Rin=imref2d(TIRFsize, [1,TIRFsize(2)],[1,TIRFsize(1)]);
            Rout = imref2d(EMsize, [1,EMsize(2)],[1,EMsize(1)]);
            if length(TIRFsize)>2
                imagedepth=size(F1image,3);
            else 
                imagedepth=1;
            end
            transformed=zeros(EMsize(1),EMsize(2),imagedepth);
            for ii=1:imagedepth
                transformed(:,:,ii)=imwarp(F1image(:,:,ii),Rin, app.info.tform,'OutputView',Rout);
            end

            
            app.picHolder{1,3} = transformed;


            app.info.FIDSt=transTIRF; 
            app.info.T=app.info.tform; %check this. Some how the new transform needs to be passed. 
            app.transformButton.Enable='on';
            app.UITable.Data=app.info.residuals;

            root=sqrt(app.info.residuals(:,1).^2+app.info.residuals(:,2).^2);
            worst=find(root==max(root(:)));
            app.EditField.Value=worst;
            
            
            showall(app);
        end

        % Button pushed function: transformButton
        function transformButtonPushed(app, event)
            

            EMimage = app.picHolder{4,1};
            EMsize = size(EMimage);
            Rout = imref2d(EMsize, [1,EMsize(2)],[1,EMsize(1)]);
            app.info.Rout=Rout;
            testButtonPushed(app);
            
            
            fullnameS = [app.info.fileHolder{1, 2}, app.info.fileHolder{1, 1}];
            dott=strfind(fullnameS, '.');
            newnameT=sprintf('%s',[fullnameS(1:(dott-1)), '_TT1.tif']);
            newnameCORR=sprintf('%s',[fullnameS(1:(dott-1)), '_corr1.tif']);
            excelname=sprintf('%s',[fullnameS(1:(dott-1)), '_residuals1b.xlsx']);
            
            if exist(excelname, 'file')
                a = xlsread(excelname, 'emCOORD');
                if ~isempty(a)
                    xlswrite(excelname,zeros(size(a))*nan, 'emCOORD');
                end
                b = xlsread(excelname, 'tirfCOORD');
                if ~isempty(b)
                    xlswrite(excelname,zeros(size(b))*nan, 'tirfCOORD');
                end
                c = xlsread(excelname, 'residuals');
                if ~isempty(c)
                    xlswrite(excelname,zeros(size(c))*nan, 'residuals');
                end
            end
            
            xlswrite(excelname,num2cell(app.info.FIDSem), 'emCOORD');
            xlswrite(excelname,num2cell(app.info.FIDSt0), 'tirfCOORD');
            xlswrite(excelname,num2cell(app.info.residuals), 'residuals');
            imwrite(app.info.overlayimage,newnameCORR,'tif');
            

            if app.info.usesnap==0
                Fimage1=app.picHolder{1,1};
            else
                Fimage1=app.picHolder{1,2};
            end
            
            Fsize=size(Fimage1);

            Rout = imref2d(EMsize, [1,EMsize(2)],[1,EMsize(1)]);
            
            if length(Fsize)>2
               imagedepth=size(Fimage1,3);
            else
                imagedepth=1;
            end
            
            
            for iframe=1:imagedepth
                Rin = imref2d(Fsize(1:2), [1,Fsize(2)],[1,Fsize(1)]);
               
                redT = Fimage1;
                currentwarped= imwarp(redT(:,:,iframe),Rin, app.info.tform, 'nearest','OutputView',Rout);
                
                %write the tif
                if iframe==1
                    imwrite(uint16(currentwarped),newnameT,'tif','Compression','none',...
                        'WriteMode','overwrite');
                    %             imwrite(uint16(greenmoviecut(:,:,iframe)),FullFileNameGw,'tif','Compression','none',...
                    %                 'WriteMode','overwrite');
                else
                    imwrite(uint16(currentwarped),newnameT,'tif','Compression','none',...
                        'WriteMode','append');
                    %             imwrite(uint16(greenmoviecut(:,:,iframe)),FullFileNameRw,'tif','Compression','none',...
                %                 'WriteMode','append');
                end
            end


            app.info.Rin=Rin;
            app.createMMMNavButton.Enable='on';
            app.transformotherButton.Enable='on';
            warning('Your images have been saved!');
            appdatainfo=app.info;
            appdatapicHolder=app.picHolder;
            save([app.info.fileHolder{1,2}, 'appdatapicHolder.mat'],"appdatapicHolder");
            save([app.info.fileHolder{1,2}, 'appdatainfo.mat'],"appdatainfo");
            warning('Your data have been saved!');
        end

        % Button pushed function: transformotherButton
        function transformotherButtonPushed(app, event)
            
            %ask for images to transform
            fileList = uipickfiles('FilterSpec','*.tif'); %pick files
            Nfiles=length(fileList); %how many files


            for ii=1:Nfiles
                currentmovie = imread(fileList{ii}); % get the specific file.
                
                moviesize=size(currentmovie);   %get the number of frames
                dot=strfind(fileList{ii}, '.');  % find the dot in the file name
                TTsavename=sprintf('%s',[fileList{ii}(1:(dot-1)), '_TT.tif']);  %create a file name for the red file or the second color
                if length(moviesize)>2
                    imagedepth=size(currentmovie,3);
                else
                    imagedepth=1;
                end
                
                for iframe=1:imagedepth
                    
                    Rin = imref2d(moviesize(1:2), [1,moviesize(2)],[1,moviesize(1)]);
                    Rout=app.info.Rout;
%                     if app.info.flip==1  flip should probably just be
%                     removed. It causes too many problems. It can be done
%                     outside of the program. 
%                        imageforT = flip(currentmovie(iframe),1);
%                     else
                        imageforT=currentmovie(:,:,iframe);
%                     end
                    
                    %%%%%%Caution, I think this will likely not work
                    %%%%%%correctly if the user did rotations prior to
                    %%%%%%flipping!
                    
                    
                    redT = imrotate(imageforT,app.info.rotation, 'nearest','crop');
                    
                    currentwarped= imwarp(redT,Rin, app.info.tform, 'nearest','OutputView',Rout);
                    
                    %write the tif
                    if iframe==1
                        imwrite(uint16(currentwarped),TTsavename,'tif','Compression','none',...
                            'WriteMode','overwrite');
                        %             imwrite(uint16(greenmoviecut(:,:,iframe)),FullFileNameGw,'tif','Compression','none',...
                        %                 'WriteMode','overwrite');
                    else
                        imwrite(uint16(currentwarped),TTsavename,'tif','Compression','none',...
                            'WriteMode','append');
                        %             imwrite(uint16(greenmoviecut(:,:,iframe)),FullFileNameRw,'tif','Compression','none',...
                    %                 'WriteMode','append');
                    end
                end
            end
            warning('Your images have been saved!');
        end

        % Button pushed function: createMMMNavButton
        function createMMMNavButtonPushed(app, event)
            %mmmalignapp(app.picHolder, app.info);
            %Originally the plan was to find the stage coordinates here and
            %put them in the NAV file. However, calculated stage
            %coordinates have inherent error because of the imperfection of
            %the stage. This is dealt with in SerialEM by checking position
            %with image correlation. But, if we place the position
            %incorrectly here outside of SerialEM, it can't' fix that. So,
            %it is better to give SErialEM pixel coordinates. This means
            %that the MRC and mdoc file need to be present when you use
            %this navigator file. This is how it will calculate approximate
            %stage coordinates. 
            [file1NAV,path1NAV] = uigetfile([app.info.fileHolder{4, 2}, '*.nav'], 'Please choose a navigator where this map is item #1.');
            
            [filepath,rootnameNAV,ext] = fileparts([path1NAV file1NAV]);
            status=copyfile([path1NAV file1NAV], [path1NAV rootnameNAV '_wROIs.nav']);
            while status==0
                wait(0.5)
            end
            fidNAV  = fopen([path1NAV rootnameNAV '_wROIs.nav'],'r+');
            fNAV=fread(fidNAV,'*char')';
            %fprintf(fidNAV,'note = hole');
            fclose(fidNAV);
            whatNAV=regexp(fNAV,'\n','split'); %I think "readlines" also does this in one line of code instead of two.
            itemlineALL = (find(contains(whatNAV,'Item')));
            
            %I think I really only need to add more items to the initial
            %Navigator. 
            if length(itemlineALL)>1
                printline=itemlineALL(2);
            else
                printline=length(whatNAV)+2;
            end
            
            %I will start printing the mmm items after the first map item. 
            
            
            linetext=whatNAV{itemlineALL(1)};
            
            eqloc=strfind(linetext,'=');
            itemNUM=str2num(linetext(eqloc+2:end-2));
            
            %change Registry to 2 for everything that we are doing here. 
            itemlineRegis = (find(contains(whatNAV,'Regis')));
            linetext=whatNAV{itemlineRegis(1)};
            linetext(9)='1'; %this was 2. I am temporarily keeping it as 1 while working on the glacios. This is hard coded in text below anyway
            whatNAV{itemlineRegis(1)}=linetext;


            %open mdoc file from the LMM acquisition. 
%             [file1MDOC,path1MDOC] = uigetfile('*.mdoc', 'Please choose the mdoc file from the montage acquisition. ');
%             fidMDOC  = fopen([path1MDOC file1MDOC],'r');
%             [filepath,rootnameMDOC,ext] = fileparts([path1MDOC file1MDOC]);
%             fMDOC=fread(fidMDOC,'*char')';
%             fclose(fidMDOC);
%             whatMDOC=regexp(fMDOC,'\n','split');
            %PieceCoordinates
            %PieceCoordinates
            %AlignedPieceCoords
%             itemlinePIECE = (find(contains(whatMDOC,'PieceCoordinates')));
%             PIECES=zeros(length(itemlinePIECE),2);
%             for ii=1:length(itemlinePIECE)
%                 linetext=whatMDOC{itemlinePIECE(ii)};
%                 spaceloc=strfind(linetext,' ');
%                 PIECES(ii,1)=str2num(linetext(spaceloc(2)+1:spaceloc(3)-1));
%                 PIECES(ii,2)=str2num(linetext(spaceloc(3)+1:spaceloc(4)-1));
%             end
            
          
            %StagePosition
%             itemlineSTAGE = (find(contains(whatMDOC,'StagePosition')));
%             
%             for ii=1:length(itemlinePIECE)
%                 linetext=whatMDOC{itemlineSTAGE(ii)};
%                 spaceloc=strfind(linetext,' ');
%                 STAGE(ii,1)=str2num(linetext(spaceloc(2)+1:spaceloc(3)-1));
%                 STAGE(ii,2)=str2num(linetext(spaceloc(3)+1:end));
%             end
            
            %camera chip size 
%             itemlineCHIP=(find(contains(whatMDOC,'ImageSize')));
%             linetext=whatMDOC{itemlineCHIP};
%             spaceloc=strfind(linetext,' ');
%             chipX=str2num(linetext(spaceloc(2)+1:spaceloc(3)-1));
%             chipY=str2num(linetext(spaceloc(3)+1:end));
            
            %stageZ
            itemlineXYZ=(find(contains(whatNAV,'StageXYZ')));
            linetext=whatNAV{itemlineXYZ(1)};
            spaceloc=strfind(linetext,' ');
            stageZ=str2num(linetext(spaceloc(4)+1:end));
            
            %mapID for drawnID: get the group ID from the map to assign
            %to the drawnID for the objects items created. 
            itemlinegroupID=(find(contains(whatNAV,'MapID')));
            linetext=whatNAV{itemlinegroupID(1)};
            spaceloc=strfind(linetext,' ');
            drawnID=str2num(linetext(spaceloc(2)+1:end));
            
            
            %here, we just want to put the piecelist into the same coordinate as our image.
%             piecelistLMMconv(:,1)=PIECES(:,1)+floor(chipX./2);
%             piecelistLMMconv(:,2)=PIECES(:,2)+floor(chipY./2);
            
            
            %This system is dependent on the first PIECE coordinate being X
            %and the second being Y. If this is not true the program will
            %mess up with images aren't square. Our glacios, the images are
            %square. 
%             piecelistLMMconv(:,2)=size(app.picHolder{4,1},1)-piecelistLMMconv(:,2);
            
            
            %now, let's convert our lmm pixels into approximate stage coordinates using
            %the piecelist

%             tform = fitgeotrans(double(piecelistLMMconv(:,1:2)),double(STAGE(:,1:2)),'similarity');
%             [XX,YY]=meshgrid(1:size(app.picHolder{4,1},2), 1:size(app.picHolder{4,1},1));
%             [stagexout1,stageyout1] = transformPointsForward(tform,double(XX),double(YY));
%             %we can keep that information but perhaps what we really want
%             %to do here is convert the positions that we picked on the
%             %image into stage coordinates. 
%             [app.info.stageMMMx,app.info.stageMMMy] = transformPointsForward(tform,double(app.info.mmmROIs(:,1)),double(app.info.mmmROIs(:,2)));
            
            %which piece is the the ROI associated with and what is the
%             %position within this piece?
%             ROIpiecenum=zeros(size(app.info.mmmROIs,1));
%             ROIpiecenum=zeros(size(app.info.mmmROIs,2));
%             for jj=1:size(app.info.mmmROIs,1)
%                 Xdists=app.info.mmmROIs(jj,1)-piecelistLMMconv(:,1);
%                 Ydists=app.info.mmmROIs(jj,2)-piecelistLMMconv(:,2);
%                 [dist,index]=min(sqrt(Xdists.^2+Ydists.^2));
%                 ROIpiecenum(jj,1)=index;
%                 XYinpiece(jj,:)=[Xdists(index)+floor(chipX./2) Ydists(index)+floor(chipY./2)];
% 
%                 clear("dist");
%                 clear("k");
%             end


%              newfilename=[path1NAV rootnameNAV '_wROIs.nav'];
%              fidOUT=fopen(newfilename,'w');
            fidNAV  = fopen([path1NAV rootnameNAV '_wROIs.nav'],'r+');
            fgetl(fidNAV);
            fgetl(fidNAV);

            for kk=3:printline-2
                fprintf(fidNAV,'%s\n',whatNAV{kk});
                
            end
                fprintf(fidNAV,'\r\n');
            %assign group number that is a random nine digit number
            groupIDmat=randi([0 9],1,9);
            groupIDstring = sprintf('%d', groupIDmat);
            %%You can calculate this all yourself...
            %However, this is problematic, When SErialEM calculates this,
            %to get the stsage coordinates, it can then adjust with image
            %correlation when the stage goes to the wrong location. But we
            %can't correct for this here, so pixel locations are a
            %fundamentally better way to go when finding locations outside
            %of SerialEM
%             for jj=1:size(app.info.mmmROIs,1)
%                 mapIDmat=randi([0 9],1,9);
%                 mapIDstring = sprintf('%d', mapIDmat);
%                 fprintf(fidOUT,'\n[Item = %i]\nColor = 0\nStageXYZ = %.3f %.3f %.2f\n', (jj+itemNUM), app.info.stageMMMx(jj), app.info.stageMMMy(jj), stageZ);
%                 fprintf(fidOUT,'NumPts = 1\nRegis = 2\nType = 0\nGroupID = %s\n', groupIDstring);
%                 fprintf(fidOUT,'DrawnID = %i\nBklshXY = 0 0\nPieceOn = %i\nXYinPc = %.2f %.2f\n', drawnID, ROIpiecenum(jj), XYinpiece(jj,1), XYinpiece(jj,2));
%                 fprintf(fidOUT,'MapID = %s\nPtsX = %.3f\nPtsY = %.3f\n\n', mapIDstring, app.info.stageMMMx(jj), app.info.stageMMMy(jj));                
%             end
            % Or let SerialEM interpret your coordinates for you. 
            for jj=1:size(app.info.mmmROIs,1)
                mapIDmat=randi([0 9],1,9);
                mapIDstring = sprintf('%d', mapIDmat);
                fprintf(fidNAV,'\r\n[Item = %i]\r\nColor = 0\r\nCoordsInAliMont = %.3f %.3f %.2f', (jj+itemNUM), app.info.mmmROIs(jj, 1), (size(app.picHolder{4,1},1)-app.info.mmmROIs(jj,2)), 0);
                fprintf(fidNAV,'\r\nNumPts = 1\r\nRegis = 1\r\nType = 0\r\nGroupID = %s\r\n', groupIDstring);
                fprintf(fidNAV,'DrawnID = %i\r\nBklshXY = 0 0\r\n', drawnID);
                fprintf(fidNAV,'MapID = %s\r\nPtsX = %.3f\r\nPtsY = %.3f\r\n\r\n', mapIDstring, app.info.mmmROIs(jj,1), (size(app.picHolder{4,1},1)-app.info.mmmROIs(jj,2)));                
            end

            
            fclose(fidNAV);
            %fclose(fidOUT);
        end

        % Button pushed function: rotate90Button
        function rotate90ButtonPushed(app, event)
            usesnap=app.info.usesnap;
            if usesnap==0
                F1image=app.picHolder{1,1};
                app.picHolder{1,1} = imrotate(F1image,90, 'nearest','loose');
            else
                F1image=app.picHolder{1,4};
                app.picHolder{1,4} = imrotate(F1image,90, 'nearest','loose');
            end
  

            app.info.rotation=app.info.rotation+90;

            showall(app);
        end

        % Callback function
        function flipButtonPushed(app, event)
%             usesnap=app.info.usesnap;
%             if usesnap==0
%                 F1image=app.picHolder{1,1};
%                 app.picHolder{1,1} = flip(F1image,1);
%             else
%                 F1image=app.picHolder{1,4};
%                 app.picHolder{1,4} = flip(F1image,1);
%             end
%             
% 
%             app.info.flip=~app.info.flip;
% 
% 
%             showall(app);
        end

        % Value changed function: EMmaxSlider
        function EMmaxSliderValueChanged(app, event)
            value = app.EMmaxSlider.Value;
            
            showall(app);
        end

        % Value changed function: EMminSlider
        function EMminSliderValueChanged(app, event)
            value = app.EMminSlider.Value;
            showall(app);
        end

        % Value changed function: fluorminSlider
        function fluorminSliderValueChanged(app, event)
            value = app.fluorminSlider.Value;
            showall(app);
        end

        % Value changed function: fluormaxSlider
        function fluormaxSliderValueChanged(app, event)
            value = app.fluormaxSlider.Value;
            showall(app);
        end

        % Button pushed function: resetpicksButton
        function resetpicksButtonPushed(app, event)
            
            % delete all transformation data
            if isfield(app.info, 'poly1')
                app.info=rmfield(app.info, 'poly1');
            end
            if isfield(app.info, 'poly2')
                app.info=rmfield(app.info, 'poly2');
            end
             if isfield(app.info, 'poly1pos')
                app.info=rmfield(app.info, 'poly1pos');
            end           
            if isfield(app.info, 'poly2pos')
                app.info=rmfield(app.info, 'poly2pos');
            end
            if isfield(app.info, 'FIDSt')
                app.info=rmfield(app.info, 'FIDSt');
            end            
            if isfield(app.info, 'FIDSem')
                app.info=rmfield(app.info, 'FIDSem');
            end
            if isfield(app.info, 'FIDSt0')
                app.info=rmfield(app.info, 'FIDSt0');
            end
            if isfield(app.info, 'T')
                app.info=rmfield(app.info, 'T');
            end            
            if isfield(app.info, 'tform')
                app.info=rmfield(app.info, 'tform');
            end            
                      
            app.EMpickButton.Enable='on';
            app.FluorpickButton.Enable='on';
            app.submitButton.Enable='off';
            app.transformButton.Enable='off';
            app.newlandmarkButton.Enable='off';
            app.transformotherButton.Enable='off';
            app.createMMMNavButton.Enable='off';
            app.removeButton.Enable='off';
            showall(app);
        end

        % Button pushed function: pickMMMROIButton
        function pickMMMROIButtonPushed(app, event)
            if isfield(app.info,'mmmROIs')
                currentROI=size(app.info.mmmROIs,1)+1;
            else
                currentROI=1;
            end
            
            Pt = drawpoint(app.UIAxes3);
            posPt = Pt.Position;
            Pt.Label=['ROI ' num2str(currentROI)];                
            app.info.mmmROIs(currentROI,:)=posPt;
            
            app.MMMremoveButton.Enable='on';
            app.MMMnum.Text=num2str(currentROI);
            app.EditField_2.Value=currentROI;
            app.createMMMNavButton.Enable='on';
            
        end

        % Button pushed function: MMMremoveButton
        function MMMremoveButtonPushed(app, event)
            badpoint=app.EditField_2.Value;
            TFmat=ones(size(app.info.mmmROIs,1),1);

            
            TFmat(badpoint)=0;
            app.info.mmmROIs=app.info.mmmROIs(logical(TFmat),:);
            app.MMMnum.Text=num2str(size(app.info.mmmROIs,1));
            
            imshow(app.info.overlayimage,[],'Parent',app.UIAxes3);      
            for ii=1:size(app.info.mmmROIs,1)
               Pt=drawpoint(app.UIAxes3, 'Position',app.info.mmmROIs(ii,:)); 
               Pt.Label=['ROI ' num2str(ii)];
            end
            app.createMMMNavButton.Enable='on';
            
        end

        % Button pushed function: adjalignmentofsquaresButton
        function adjalignmentofsquaresButtonPushed(app, event)
            %assume largest grid size will be 200 mesh for now. This is 125
            %um pitch. 177 um at diameter
            %app.info.pixSize(4)=357.5; %This needs to be requested not hard coded in. 
            %halfcropsize=floor(1000.0*177./(app.info.pixSize(4).*2)); %This should be in pixels. 
            halfcropsize=250; %This number makes a lot of assumptions about the size of the grid etc but I think should cover most scenarios. 
            app.info.halfcropsize=uint16(halfcropsize);
            ROItotN=uint16(size(app.info.mmmROIs,1));
            app.info.croppedEM=zeros(2.*halfcropsize+1, 2.*halfcropsize+1, ROItotN);
            if size(app.picHolder{1,3},3)==3
                app.info.croppedF=zeros(2.*halfcropsize+1, 2.*halfcropsize+1,3,ROItotN);
                %app.info.croppedFmod=zeros(2.*halfcropsize+1, 2.*halfcropsize+1,3,ROItotN);
            else
                app.info.croppedF=zeros(2.*halfcropsize+1, 2.*halfcropsize+1, ROItotN);
                %app.info.croppedFmod=zeros(2.*halfcropsize+1, 2.*halfcropsize+1, ROItotN);
            end
            
            for jj=1:ROItotN
                %Here, I need to take into account the edge of the image.
                %It is possible someone could pick a region at the edge of
                %the EM image. This is problematic further in the program
                %though. The program won't be able to correlate an image
                %that is cut off. In the case that someone picks an area at
                %the edge of the image, I could just zeropad.
                
                app.info.croppedEM(:,:,jj)=app.picHolder{4,1}(uint16(app.info.mmmROIs(jj,2)-halfcropsize):uint16(app.info.mmmROIs(jj,2)+halfcropsize),uint16(app.info.mmmROIs(jj,1)-halfcropsize):uint16(app.info.mmmROIs(jj,1)+halfcropsize));
                if size(app.picHolder{1,3},3)==3
                    
                    app.info.croppedF(:,:,:,jj)=app.picHolder{1,3}(uint16(app.info.mmmROIs(jj,2)-halfcropsize):uint16(app.info.mmmROIs(jj,2)+halfcropsize),uint16(app.info.mmmROIs(jj,1)-halfcropsize):uint16(app.info.mmmROIs(jj,1)+halfcropsize), :);
                else
                    app.info.croppedF(:,:,jj)=app.picHolder{1,3}(uint16(app.info.mmmROIs(jj,2)-halfcropsize):uint16(app.info.mmmROIs(jj,2)+halfcropsize),uint16(app.info.mmmROIs(jj,1)-halfcropsize):uint16(app.info.mmmROIs(jj,1)+halfcropsize));
                end
            end
            
            app.info.ROItotN=ROItotN;
            %declare additions to info that will happen in roishiftapp. 
            app.info.currentROI=1;
            app.info.shift=zeros(ROItotN, 2);
            if ~isfield(app.info,'totalshift')
                app.info.totalshift=zeros(ROItotN, 2);
            end
            appdatainfo=app.info;
            assignin('base','appinfofluor', app.info.croppedF);
            appdatapicHolder=app.picHolder;
            save([app.info.fileHolder{1,2}, 'appdatapicHolder.mat'],"appdatapicHolder");
            save([app.info.fileHolder{1,2}, 'appdatainfo.mat'],"appdatainfo");
            
            app.info.shiftpoints=zeros(ROItotN, 4);
            %mmm_info=app.info;
            roishiftapp(app.info, app.picHolder);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'EM map')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Fluorescence map')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Correlated map')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.EMmaxLabel = uilabel(app.UIFigure);
            app.EMmaxLabel.Text = {'EM '; 'max'};
            app.EMmaxSlider = uislider(app.UIFigure);
            app.EMmaxSlider.Limits = [0 255];
            app.EMmaxSlider.MajorTicks = [];
            app.EMmaxSlider.ValueChangedFcn = createCallbackFcn(app, @EMmaxSliderValueChanged, true);
            app.EMmaxSlider.MinorTicks = [];
            app.EMmaxSlider.Value = 100;
            app.EMminLabel = uilabel(app.UIFigure);
            app.EMminLabel.Text = {'EM '; 'min'};
            app.EMminSlider = uislider(app.UIFigure);
            app.EMminSlider.MajorTicks = [];
            app.EMminSlider.ValueChangedFcn = createCallbackFcn(app, @EMminSliderValueChanged, true);
            app.EMminSlider.MinorTicks = [];
            app.fluorminLabel = uilabel(app.UIFigure);
            app.fluorminLabel.Text = {'fluor'; 'min'};
            app.fluorminSlider = uislider(app.UIFigure);
            app.fluorminSlider.MajorTicks = [];
            app.fluorminSlider.ValueChangedFcn = createCallbackFcn(app, @fluorminSliderValueChanged, true);
            app.fluorminSlider.MinorTicks = [];
            app.fluormaxLabel = uilabel(app.UIFigure);
            app.fluormaxLabel.Text = {'fluor'; 'max'};
            app.fluormaxSlider = uislider(app.UIFigure);
            app.fluormaxSlider.MajorTicks = [];
            app.fluormaxSlider.ValueChangedFcn = createCallbackFcn(app, @fluormaxSliderValueChanged, true);
            app.fluormaxSlider.MinorTicks = [];
            app.fluormaxSlider.Value = 100;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X residual'; 'Y residual'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.testButton = uibutton(app.UIFigure, 'push');
            app.testButton.ButtonPushedFcn = createCallbackFcn(app, @testButtonPushed, true);
            app.testButton.Text = 'test';
            app.removeButton = uibutton(app.UIFigure, 'push');
            app.removeButton.ButtonPushedFcn = createCallbackFcn(app, @removeButtonPushed, true);
            app.removeButton.Text = 'remove';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EMpickButton = uibutton(app.UIFigure, 'push');
            app.EMpickButton.ButtonPushedFcn = createCallbackFcn(app, @EMpickButtonPushed, true);
            app.EMpickButton.Text = 'EM pick';
            app.FluorpickButton = uibutton(app.UIFigure, 'push');
            app.FluorpickButton.ButtonPushedFcn = createCallbackFcn(app, @FluorpickButtonPushed, true);
            app.FluorpickButton.Text = 'Fluor pick';
            app.rotate90Button = uibutton(app.UIFigure, 'push');
            app.rotate90Button.ButtonPushedFcn = createCallbackFcn(app, @rotate90ButtonPushed, true);
            app.rotate90Button.Text = 'rotate 90';
            app.submitButton = uibutton(app.UIFigure, 'push');
            app.submitButton.ButtonPushedFcn = createCallbackFcn(app, @submitButtonPushed, true);
            app.submitButton.Text = 'submit';
            app.newlandmarkButton = uibutton(app.UIFigure, 'push');
            app.newlandmarkButton.ButtonPushedFcn = createCallbackFcn(app, @newlandmarkButtonPushed, true);
            app.newlandmarkButton.Text = 'new landmark';
            app.transformButton = uibutton(app.UIFigure, 'push');
            app.transformButton.ButtonPushedFcn = createCallbackFcn(app, @transformButtonPushed, true);
            app.transformButton.Text = 'transform';
            app.createMMMNavButton = uibutton(app.UIFigure, 'push');
            app.createMMMNavButton.ButtonPushedFcn = createCallbackFcn(app, @createMMMNavButtonPushed, true);
            app.createMMMNavButton.Text = 'create MMM Nav';
            app.transformotherButton = uibutton(app.UIFigure, 'push');
            app.transformotherButton.ButtonPushedFcn = createCallbackFcn(app, @transformotherButtonPushed, true);
            app.transformotherButton.Text = 'transform other';
            app.resetpicksButton = uibutton(app.UIFigure, 'push');
            app.resetpicksButton.ButtonPushedFcn = createCallbackFcn(app, @resetpicksButtonPushed, true);
            app.resetpicksButton.Text = 'reset picks';
            app.pickMMMROIButton = uibutton(app.UIFigure, 'push');
            app.pickMMMROIButton.ButtonPushedFcn = createCallbackFcn(app, @pickMMMROIButtonPushed, true);
            app.pickMMMROIButton.Text = 'pick MMM ROI';
            app.MMMnum = uilabel(app.UIFigure);
            app.MMMnum.Text = '0';
            app.MMMremoveButton = uibutton(app.UIFigure, 'push');
            app.MMMremoveButton.ButtonPushedFcn = createCallbackFcn(app, @MMMremoveButtonPushed, true);
            app.MMMremoveButton.Text = 'MMMremove';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.adjalignmentofsquaresButton = uibutton(app.UIFigure, 'push');
            app.adjalignmentofsquaresButton.ButtonPushedFcn = createCallbackFcn(app, @adjalignmentofsquaresButtonPushed, true);
            app.adjalignmentofsquaresButton.Text = 'adj. alignment of squares';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lmmalignapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end