classdef roishiftapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        HigherMagCorrelationButton  matlab.ui.control.Button
        FluormaxSlider              matlab.ui.control.Slider
        FluormaxSliderLabel         matlab.ui.control.Label
        FluorminSlider              matlab.ui.control.Slider
        FluorminSliderLabel         matlab.ui.control.Label
        EMmaxSlider                 matlab.ui.control.Slider
        EMmaxSliderLabel            matlab.ui.control.Label
        EMminSlider                 matlab.ui.control.Slider
        EMminSliderLabel            matlab.ui.control.Label
        GoToButton                  matlab.ui.control.Button
        GoToEditField               matlab.ui.control.NumericEditField
        exportButton                matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        defineshiftButton           matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        info % Description
        picHolder % Description
    end
    
    methods (Access = private)
        
        function showall(app)
            app.UIAxes.Title.String=['ROI' num2str(app.info.currentROI)];
            
            EMmin=app.EMminSlider.Value;
            EMmax=app.EMmaxSlider.Value;
            
         
            Fmin=app.FluorminSlider.Value;
            Fmax=app.FluormaxSlider.Value;

            EMdisplay=255.0.*((double(app.info.croppedEM(:,:,uint8(app.info.currentROI)))-EMmin)./(EMmax-EMmin));
            EMdisplay(EMdisplay>255)=255;
            if length(size(app.info.croppedF))==4
                FL1display=255.0.*((double(app.info.croppedF(:,:,:,uint8(app.info.currentROI)))-Fmin)./(Fmax-Fmin));
                FL1display(FL1display>255)=255; 
            else
                FL1display=255.0.*((double(app.info.croppedF(:,:,uint8(app.info.currentROI)))-Fmin)./(Fmax-Fmin));
                FL1display(FL1display>255)=255;  
            end
        
            
            EMrgb=cat(3,uint8(EMdisplay),uint8(EMdisplay),uint8(EMdisplay));
            if size(FL1display,3)==3
                Frgb=uint8(FL1display);
            
            else
                Frgb=cat(3,uint8(FL1display),uint8(FL1display).*0,uint8(FL1display));
            end
            
            DISPLAYimage=uint8(0.5.*EMrgb)+uint8(Frgb);
            app.info.overlayROIS(:,:,:,app.info.currentROI)=DISPLAYimage;
            imshow(DISPLAYimage,[],'Parent',app.UIAxes); 

        end
        
        
        function updatetable(app)
            app.UITable.Data=app.info.totalshift;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, infoMOM, picHolderMOM)
           
            app.info=infoMOM;
            app.picHolder=picHolderMOM;
            
            app.info.currentROI=1;
            if app.info.ROItotN > 1
                app.GoToEditField.Value=2;
            else
                app.GoToEditField.Value=1;
            end
            if ~isfield(app.info,'shift')
                app.info.totalshift=zeros(app.info.ROItotN,2);
            end
            if size(app.info.totalshift,1)~=app.info.ROItotN
                app.info.totalshift=zeros(app.info.ROItotN,2);
            end
            app.info.shift=zeros(app.info.ROItotN,2);

            EMmaxtotal=max(app.info.croppedEM(:));
            app.EMmaxSlider.Limits=[0 EMmaxtotal];
            app.EMminSlider.Limits=[0 EMmaxtotal];
            app.EMmaxSlider.Value=EMmaxtotal;
            app.EMminSlider.Value=0;           

            
            updatetable(app);
            showall(app);
        end

        % Button pushed function: defineshiftButton
        function defineshiftButtonPushed(app, event)

            
            linedPt = drawline(app.UIAxes);
            
            posLine = linedPt.Position;
            delete(linedPt);
            app.info.shiftpts(app.info.currentROI, 1) = posLine(1, 1);
            app.info.shiftpts(app.info.currentROI, 2) = posLine(1, 2);
            app.info.shiftpts(app.info.currentROI, 3) = posLine(2, 1);
            app.info.shiftpts(app.info.currentROI, 4) = posLine(2, 2);
           
            app.info.totalshift(app.info.currentROI, 1)=app.info.totalshift(app.info.currentROI, 1)+app.info.shiftpts(app.info.currentROI, 3)-app.info.shiftpts(app.info.currentROI, 1);
            app.info.totalshift(app.info.currentROI, 2)=app.info.totalshift(app.info.currentROI, 2)+app.info.shiftpts(app.info.currentROI, 4)-app.info.shiftpts(app.info.currentROI, 2);
            app.info.shift(app.info.currentROI, 1)=app.info.shiftpts(app.info.currentROI, 3)-app.info.shiftpts(app.info.currentROI, 1);
            app.info.shift(app.info.currentROI, 2)=app.info.shiftpts(app.info.currentROI, 4)-app.info.shiftpts(app.info.currentROI, 2);
            
            app.UITable.Data=app.info.shift;
            if length(size(app.info.croppedF))==4
                app.info.croppedF(:,:,:,app.info.currentROI) = imtranslate(app.info.croppedF(:,:,:,app.info.currentROI),app.info.shift(app.info.currentROI,:),'FillValues',0,'method','nearest', 'OutputView','same');
            else
                app.info.croppedF(:,:,app.info.currentROI) = imtranslate(app.info.croppedF(:,:,:,app.info.currentROI),app.info.shift(app.info.currentROI,:),'FillValues',0,'method','nearest', 'OutputView','same');
            end
            updatetable(app);
            showall(app);
        end

        % Value changed function: EMminSlider
        function EMminSliderValueChanged(app, event)
            value = app.EMminSlider.Value;
            showall(app);
        end

        % Value changed function: EMmaxSlider
        function EMmaxSliderValueChanged(app, event)
            value = app.EMmaxSlider.Value;
            showall(app);
        end

        % Value changed function: FluorminSlider
        function FluorminSliderValueChanged(app, event)
            value = app.FluorminSlider.Value;
            showall(app);
        end

        % Value changed function: FluormaxSlider
        function FluormaxSliderValueChanged(app, event)
            value = app.FluormaxSlider.Value;
            showall(app);
        end

        % Button pushed function: GoToButton
        function GoToButtonPushed(app, event)
            if app.GoToEditField.Value<=app.info.ROItotN
                app.info.currentROI=app.GoToEditField.Value;
                if app.GoToEditField.Value<app.info.ROItotN
                    app.GoToEditField.Value=app.GoToEditField.Value+1;
                end
            else 
                app.info.currentROI=app.info.ROItotN;
                app.GoToEditField.Value=app.info.ROItotN;
            end
            assignin('base','shiftapptotalshift', app.info.totalshift)
            showall(app);
        end

        % Button pushed function: exportButton
        function exportButtonPushed(app, event)
            opts.Interpreter = 'tex';
            opts.Default = 'No';
            answer = questdlg('Would you like to crop other fluorescence images as well?','Others?','Yes','No', opts);
            % Handle response
            switch answer
                case 'Yes'
                    otherfilesQ = 1;
                    fileList = uipickfiles('FilterSpec','*.tif'); %pick files
                case 'No'
                    otherfilesQ = 0;
            end


            %First, crop the files that we already have. The EM crops have
            %already been made and just need to be saved. The fluorescence
            %crops need to be remade with their new positions.
            halfcropsize=app.info.halfcropsize;
            totalshift=app.info.totalshift;
            assignin('base',"totalshift",totalshift);
            
            oldpositions=round(app.info.mmmROIs);
            assignin('base','oldpositions',oldpositions);
            newpositionsX=oldpositions(:,1)-totalshift(:,1);
            newpositionsY=oldpositions(:,2)-totalshift(:,2);
            newpositions=cat(2,newpositionsX,newpositionsY);
            assignin('base','newpositions',newpositions);
            for jj=1:app.info.ROItotN
                EMnewfilename=['ROI', num2str(jj),'_', app.info.fileHolder{4,1}];
                Fnewfilename=['ROI', num2str(jj),'_', app.info.fileHolder{1,1}];
                Fregsavename=[app.info.fileHolder{1,2}, Fnewfilename];
                EMregsavename=[app.info.fileHolder{1,2}, EMnewfilename];
                imwrite(uint16(app.info.croppedEM(:,:,jj)),EMregsavename,'tif','Compression','none',...
                            'WriteMode','overwrite');
                if size(app.picHolder{1,3},3)==3  
                    app.info.croppedF(:,:,:,jj)=app.picHolder{1,3}((newpositions(jj,2)-halfcropsize):(newpositions(jj,2)+halfcropsize),(newpositions(jj,1)-halfcropsize):(newpositions(jj,1)+halfcropsize), :);
                    for iframe=1:3
                        if iframe==1
                            imwrite(uint16(app.info.croppedF(:,:,iframe,jj)),Fregsavename,'tif','Compression','none',...
                                          'WriteMode','overwrite');
                        else
                            imwrite(uint16(app.info.croppedF(:,:,iframe,jj)),Fregsavename,'tif','Compression','none',...
                                         'WriteMode','append');
                        end
                    end
                else
                    app.info.croppedF(:,:,jj)=app.picHolder{1,3}((newpositions(jj,2)-halfcropsize):(newpositions(jj,2)+halfcropsize),(newpositions(jj,1)-halfcropsize):(newpositions(jj,1)+halfcropsize));
                    
                    imwrite(uint16(app.info.croppedF(:,:,jj)),Fregsavename,'tif','Compression','none',...
                            'WriteMode','overwrite');
                end
                
            end
            
            
            if otherfilesQ==1

                Nfiles=length(fileList); %how many files
               
                
                for ii=1:Nfiles
                    currentfile = imread(fileList{ii}); % get the specific file.
                    filesize=size(currentfile);   %get the number of frames
                    backslash=strfind(fileList{ii}, '\');  % find the dot in the file name
                    if length(filesize)>2
                        imagedepth=size(currentfile,3);
                    else
                        imagedepth=1;
                    end
                    
                    for jj=1:app.info.ROItotN
                        REGsavename=[app.info.fileHolder{1,2},'ROI', num2str(jj),'_', fileList{ii}((backslash(end)+1):end)];
                        if imagedepth==1
                            savedimage=currentfile((newpositions(jj,2)-halfcropsize):(newpositions(jj,2)+halfcropsize),(newpositions(jj,1)-halfcropsize):(newpositions(jj,1)+halfcropsize));
                        else
                            savedimage=currentfile((newpositions(jj,2)-halfcropsize):(newpositions(jj,2)+halfcropsize),(newpositions(jj,1)-halfcropsize):(newpositions(jj,1)+halfcropsize), :);
                        end
                        for iframe=1:imagedepth
                            %write the tif
                            if iframe==1
                                imwrite(uint16(savedimage),REGsavename,'tif','Compression','none',...
                                    'WriteMode','overwrite');
                                %             imwrite(uint16(greenmoviecut(:,:,iframe)),FullFileNameGw,'tif','Compression','none',...
                                %                 'WriteMode','overwrite');
                            else
                                imwrite(uint16(savedimage),REGsavename,'tif','Compression','none',...
                                    'WriteMode','append');
                                %             imwrite(uint16(greenmoviecut(:,:,iframe)),FullFileNameRw,'tif','Compression','none',...
                            %                 'WriteMode','append');
                            end
                        end
                    end
                end
                clear('savedimage');
            end
            warning('Your images have been saved!');
            appdatainfo=app.info;
            appdatapicHolder=app.picHolder;
            save([app.info.fileHolder{1,2}, 'appdatapicHolder.mat'],"appdatapicHolder");
            save([app.info.fileHolder{1,2}, 'appdatainfo.mat'],"appdatainfo");
            warning('Your data have been saved!');
            
            %autocorrMMM(app.info, app.picHolder);
            
        end

        % Button pushed function: HigherMagCorrelationButton
        function HigherMagCorrelationButtonPushed(app, event)
            autocorrMMM(app.info, app.picHolder);
            appdatainfo=app.info;
            appdatapicHolder=app.picHolder;
            save([app.info.fileHolder{1,2}, 'appdatapicHolder.mat'],"appdatapicHolder");
            save([app.info.fileHolder{1,2}, 'appdatainfo.mat'],"appdatainfo");
            warning('Your data have been saved!');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.defineshiftButton = uibutton(app.UIFigure, 'push');
            app.defineshiftButton.ButtonPushedFcn = createCallbackFcn(app, @defineshiftButtonPushed, true);
            app.defineshiftButton.Text = 'define shift';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.exportButton = uibutton(app.UIFigure, 'push');
            app.exportButton.ButtonPushedFcn = createCallbackFcn(app, @exportButtonPushed, true);
            app.exportButton.Text = 'export';
            app.GoToEditField = uieditfield(app.UIFigure, 'numeric');
            app.GoToButton = uibutton(app.UIFigure, 'push');
            app.GoToButton.ButtonPushedFcn = createCallbackFcn(app, @GoToButtonPushed, true);
            app.GoToButton.Text = 'Go To';
            app.EMminSliderLabel = uilabel(app.UIFigure);
            app.EMminSliderLabel.Text = 'EM min';
            app.EMminSlider = uislider(app.UIFigure);
            app.EMminSlider.Limits = [0 255];
            app.EMminSlider.MajorTicks = [];
            app.EMminSlider.ValueChangedFcn = createCallbackFcn(app, @EMminSliderValueChanged, true);
            app.EMminSlider.MinorTicks = [];
            app.EMmaxSliderLabel = uilabel(app.UIFigure);
            app.EMmaxSliderLabel.Text = 'EM max';
            app.EMmaxSlider = uislider(app.UIFigure);
            app.EMmaxSlider.Limits = [0 255];
            app.EMmaxSlider.MajorTicks = [];
            app.EMmaxSlider.ValueChangedFcn = createCallbackFcn(app, @EMmaxSliderValueChanged, true);
            app.EMmaxSlider.MinorTicks = [];
            app.EMmaxSlider.Value = 255;
            app.FluorminSliderLabel = uilabel(app.UIFigure);
            app.FluorminSliderLabel.Text = 'Fluor min';
            app.FluorminSlider = uislider(app.UIFigure);
            app.FluorminSlider.Limits = [0 255];
            app.FluorminSlider.MajorTicks = [];
            app.FluorminSlider.ValueChangedFcn = createCallbackFcn(app, @FluorminSliderValueChanged, true);
            app.FluorminSlider.MinorTicks = [];
            app.FluormaxSliderLabel = uilabel(app.UIFigure);
            app.FluormaxSliderLabel.Text = 'Fluor max';
            app.FluormaxSlider = uislider(app.UIFigure);
            app.FluormaxSlider.Limits = [0 255];
            app.FluormaxSlider.MajorTicks = [];
            app.FluormaxSlider.ValueChangedFcn = createCallbackFcn(app, @FluormaxSliderValueChanged, true);
            app.FluormaxSlider.MinorTicks = [];
            app.FluormaxSlider.Value = 255;
            app.HigherMagCorrelationButton = uibutton(app.UIFigure, 'push');
            app.HigherMagCorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @HigherMagCorrelationButtonPushed, true);
            app.HigherMagCorrelationButton.Text = 'Higher Mag Correlation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = roishiftapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end